classdef nVoke_ROI_tag < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CheckROIsandTagRecordingsLabel  matlab.ui.control.Label
        DataPanel                       matlab.ui.container.Panel
        ROIdatafileEditFieldLabel       matlab.ui.control.Label
        ROIdatafileEditField            matlab.ui.control.EditField
        ViarablenameLabel               matlab.ui.control.Label
        viarableloadedLabel_name        matlab.ui.control.Label
        BrowseButton_ROIdata            matlab.ui.control.Button
        DataPanel_2                     matlab.ui.container.Panel
        ROIdatafileEditField_2Label     matlab.ui.control.Label
        ROIdatafileEditField_2          matlab.ui.control.EditField
        ViarablenameLabel_2             matlab.ui.control.Label
        viarableloadedLabel_name_2      matlab.ui.control.Label
        BrowseButton_ROIdata_2          matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PlotTab                         matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_triggered                matlab.ui.control.UIAxes
        PreviousROIButton               matlab.ui.control.Button
        NextROIButton                   matlab.ui.control.Button
        RecordingnameLabel              matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        UIAxes_triggered_single         matlab.ui.control.UIAxes
        PreviousStimulationButton       matlab.ui.control.Button
        NextStimulationButton           matlab.ui.control.Button
        Table                           matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        TagsPanel                       matlab.ui.container.Panel
        StimulationDropDownLabel        matlab.ui.control.Label
        StimulationDropDown             matlab.ui.control.DropDown
        EditField_stim                  matlab.ui.control.EditField
        StimulationdurationsLabel       matlab.ui.control.Label
        StimulationdurationsDropDown    matlab.ui.control.DropDown
        StableBaselineDropDownLabel     matlab.ui.control.Label
        StableBaselineDropDown          matlab.ui.control.DropDown
        CalciumIndicatorDropDownLabel   matlab.ui.control.Label
        CalciumIndicatorDropDown        matlab.ui.control.DropDown
        EditField_CaIn                  matlab.ui.control.EditField
        EditField_stim_duration         matlab.ui.control.NumericEditField
        StimulationEffectsSingleTrialPanel  matlab.ui.container.Panel
        InduceCalciumtransientCheckBox  matlab.ui.control.CheckBox
        SupressCalciumtransientCheckBox  matlab.ui.control.CheckBox
        ReboundCalciumtransientafterstimulationCheckBox  matlab.ui.control.CheckBox
        RecordingFrequencyHzDropDownLabel  matlab.ui.control.Label
        RecordingFrequencyHzDropDown    matlab.ui.control.DropDown
        EditField_record_fq             matlab.ui.control.NumericEditField
        PartofneuronDropDownLabel       matlab.ui.control.Label
        PartofneuronDropDown            matlab.ui.control.DropDown
        EditField_neuron_part           matlab.ui.control.EditField
        MouseIDEditFieldLabel           matlab.ui.control.Label
        MouseIDEditField                matlab.ui.control.EditField
        DOBDatePickerLabel              matlab.ui.control.Label
        DOBDatePicker                   matlab.ui.control.DatePicker
        DOEDatePickerLabel              matlab.ui.control.Label
        DOEDatePicker                   matlab.ui.control.DatePicker
        WeightgEditFieldLabel           matlab.ui.control.Label
        WeightgEditField                matlab.ui.control.NumericEditField
        OptoactuatorDropDownLabel       matlab.ui.control.Label
        OptoactuatorDropDown            matlab.ui.control.DropDown
        EditField_opto_actuator         matlab.ui.control.EditField
        SaveTagsButton                  matlab.ui.control.Button
        DOIDatePickerLabel              matlab.ui.control.Label
        DOIDatePicker                   matlab.ui.control.DatePicker
        EditField_transfection_day      matlab.ui.control.NumericEditField
        EditField_age                   matlab.ui.control.NumericEditField
        SexDropDownLabel                matlab.ui.control.Label
        SexDropDown                     matlab.ui.control.DropDown
        AnesthesiaPanel                 matlab.ui.container.Panel
        IsofluraneCheckBox              matlab.ui.control.CheckBox
        ChloraloseCheckBox              matlab.ui.control.CheckBox
        ConcentrationDropDown           matlab.ui.control.DropDown
        EditField_IsoCon                matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        NotesTextAreaLabel              matlab.ui.control.Label
        NotesTextArea                   matlab.ui.control.TextArea
        daysLabel                       matlab.ui.control.Label
        daysLabel_2                     matlab.ui.control.Label
        ExportMatfileButton             matlab.ui.control.Button
        NoTagInfoLabel                  matlab.ui.control.Label
        DiscardthisROICheckBox          matlab.ui.control.CheckBox
        SameFOVexistsCheckBox           matlab.ui.control.CheckBox
        ListBox                         matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        folder_ROIdata = 'G:\Workspace\Inscopix_Seagate\Analysis\IO_GCaMP-IO_ChrimsonR-CN_ventral\ROI_data'; % default folder for ROIdata mat files
        folder_plots = 'G:\Workspace\Inscopix_Seagate\Analysis\IO_GCaMP-IO_ChrimsonR-CN_ventral\ROI_data\peaks'; % default folder for ventral approach plots
        lowpass_fpass = 0.1;
        highpass_fpass = 0.1;
        peakinfo_row_name = 'Peak_lowpassed';
        ROIdataVar; 
        ROIdata;
        stimulation_tag;
        roi_num_all; % current recording total roi number
        stimuli_num_all;
        rn = 1; % recording number
        roi_n = 1; % roi number
        tn = 1; % trigger number: the order number of stimulation in one ROI
        trig_plot;
        gpio_x_trig_plot;
        gpio_y_trig_plot;
        pre_stimuli_duration = 3; % duration before stimulation onset
        post_stimuli_duration = 6; % duration after stimulation end
        baseline_duration = 1; % time duration before stimulation used to calculate baseline for y-axis aligment
        peak_trig_plot;
        tagtable;
        tag_info;
        discard_tag;
        TagName = {'Recording', 'ROI', 'Stim_Order', 'MouseID', 'Sex',...
            'DOB', 'DOI', 'DOE', 'Transfection_day', 'Age',...
            'Weight', 'Ca_Indicator', 'Opto_Actuator', 'Rec_Fr', 'Part_of_neuron',...
            'Stimulation', 'Stim_duration', 'Stable_baseline', 'Isoflurane', 'Isoflurane_con',...
            'Chloralose', 'Induce_transient', 'Supress_transient', 'Rebound_transient', 'Notes'};
    end
    
    methods (Access = private)
        
        function [recording_timeinfo, roi_rawdata, roi_lowpasseddata] = PlotRoi(app, roidata, recording_num, roi_num) % plot roi data in axes
            rn = recording_num;
            roi_n = roi_num;

            peakinfo_row = find(strcmp(app.peakinfo_row_name, roidata{rn, 5}.Properties.RowNames)); % specifi which type of peak info will be used: raw, lowpassed, smoothed
            recording_rawdata = roidata{rn, 2};
            [recording_rawdata, recording_time, roi_num_all] = ROI_calc_plot(recording_rawdata);
            recording_timeinfo = recording_rawdata{:, 1}; % array not table
            recording_fr = round(1/(recording_timeinfo(2)-recording_timeinfo(1))); % recording frequency
            switch recording_fr
            case 10
                app.RecordingFrequencyHzDropDown.Value = app.RecordingFrequencyHzDropDown.Items{1};
            case 20
                app.RecordingFrequencyHzDropDown.Value = app.RecordingFrequencyHzDropDown.Items{2};
            case 30
                app.RecordingFrequencyHzDropDown.Value = app.RecordingFrequencyHzDropDown.Items{3};
            otherwise
                app.RecordingFrequencyHzDropDown.Value = app.RecordingFrequencyHzDropDown.Items{4};
                app.EditField_record_fq.Enable = 1;
                app.EditField_record_fq.Value = recording_fr;
            end

            roi_name = roidata{rn,5}.Properties.VariableNames{roi_n}; % roi name ('C0, C1...')
            app.roi_num_all = size(roidata{app.rn, 2}, 2)-1; % total roi number in recording-app.rn
            app.UIAxes.Title.String = [roi_name, ' ', num2str(roi_n), '/', num2str(app.roi_num_all)];
            roi_rawdata_loc = find(strcmp(roi_name, recording_rawdata.Properties.VariableNames));
            roi_rawdata = recording_rawdata{:, roi_rawdata_loc};
            roi_lowpasseddata = lowpass(roi_rawdata, app.lowpass_fpass, recording_fr);
            % roi_highpassed = highpass(roi_rawdata, app.highpass_fpass, recording_fr(rn));

            % thresh = mean(roi_highpassed)+5*std(roi_highpassed);
            % recording_thresh{:, roi_rawdata_loc} = ones(size(recording_timeinfo))*thresh;

            peak_loc_time = roidata{rn, 5}{peakinfo_row, roi_n}{:, :}.('Peak_loc_s_'); % peaks' time
            rise_start_time = roidata{rn, 5}{peakinfo_row, roi_n}{:, :}.('Rise_start_s_');
            decay_stop_time = roidata{rn, 5}{peakinfo_row, roi_n}{:, :}.('Decay_stop_s_');
            peak_num = length(peak_loc_time);
            for pn = 1:peak_num
                [min_peak closestIndex_peak] = min(abs(recording_timeinfo-peak_loc_time(pn)));
                [min_rise closestIndex_rise] = min(abs(recording_timeinfo-rise_start_time(pn)));
                [min_decay closestIndex_decay] = min(abs(recording_timeinfo-decay_stop_time(pn)));

                roidata{rn, 5}{peakinfo_row, roi_n}{:, :}.('Peak_loc')(pn) = closestIndex_peak;
                roidata{rn, 5}{peakinfo_row, roi_n}{:, :}.('Rise_start')(pn) = closestIndex_rise;
                roidata{rn, 5}{peakinfo_row, roi_n}{:, :}.('Decay_stop')(pn) = closestIndex_decay;

                roidata{rn, 5}{peakinfo_row, roi_n}{:, :}.('Peak_mag')(pn) = roi_lowpasseddata(closestIndex_peak);
                roidata{rn, 5}{peakinfo_row, roi_n}{:, :}.('Rise_duration_s_')(pn) = peak_loc_time(pn)-rise_start_time(pn);
                roidata{rn, 5}{peakinfo_row, roi_n}{:, :}.('decay_duration_s_')(pn) = decay_stop_time(pn)-peak_loc_time(pn);

                peakmag_relative_rise = roi_lowpasseddata(closestIndex_peak)-roi_lowpasseddata(closestIndex_rise);
                peakmag_relative_decay = roi_lowpasseddata(closestIndex_peak)-roi_lowpasseddata(closestIndex_decay);
                roidata{rn, 5}{peakinfo_row, roi_n}{:, :}.('Peak_mag_relative')(pn) = max(peakmag_relative_rise, peakmag_relative_decay);
            end
            app.ROIdata = roidata;
        end
        
        function [gpio_x, gpio_y, gpio_train_start_time, gpio_train_end_time] = extract_gpio_into(app, roidata, recording_num)
            gpio_info = roidata{recording_num, 4}(3).time_value; % first column: time. second column: voltage
            gpio_rise_loc = find(gpio_info(:, 2)); % locations of GPIO voltage not 0, ie stimuli start
            gpio_rise_num = length(gpio_rise_loc); % number of GPIO voltage rise

            % Looking for stimulation groups. Many stimuli are train signal. Ditinguish trains by finding rise time interval >=5s
            % Next line calculate time interval between to gpio_rise. (Second:End stimuli_time)-(first:Second_last stimuli_time)
            gpio_rise_interval = gpio_info(gpio_rise_loc(2:end), 1)-gpio_info(gpio_rise_loc(1:(end-1)), 1);
            train_interval_loc = find(gpio_rise_interval >= 5); % If time interval >=5s, this is between end of a train and start of another train
            train_end_loc = [gpio_rise_loc(train_interval_loc); gpio_rise_loc(end)]; % time of the train_end rises start
            train_start_loc = [gpio_rise_loc(1); gpio_rise_loc(train_interval_loc+1)]; % time of the train_start rises start
            
            gpio_train_start_time = gpio_info(train_start_loc, 1); % time point when GPIO trains start
            gpio_train_end_time = gpio_info(train_end_loc+1, 1); % time point when GPIO trains end
            gpio_train_interval_time = gpio_train_start_time(2, 1)-gpio_train_start_time(1, 1); % between the first train to second train start
            gpio_train_interval_time = round(gpio_train_interval_time); % round the interval to closest integer
            gpio_train_duration = gpio_train_end_time(1, 1)-gpio_train_start_time(1, 1); % duration of 1 train stimulation
            gpio_train_duration = round(gpio_train_duration);

            switch gpio_train_duration
            case 1
                app.StimulationdurationsDropDown.Value = app.StimulationdurationsDropDown.Items{2};
            case 5
                app.StimulationdurationsDropDown.Value = app.StimulationdurationsDropDown.Items{3};
            case 10
                app.StimulationdurationsDropDown.Value = app.StimulationdurationsDropDown.Items{4};
            otherwise
                app.StimulationdurationsDropDown.Value = app.StimulationdurationsDropDown.Items{5};
                app.EditField_stim_duration.Enable = 1;
            end

            for ng = 1:gpio_rise_num % number of GPIO voltage rise, ie stimuli
                gpio_x(1+(ng-1)*4, 1) = gpio_info(gpio_rise_loc(ng), 1);
                gpio_x(2+(ng-1)*4, 1) = gpio_info(gpio_rise_loc(ng), 1);
                gpio_x(3+(ng-1)*4, 1) = gpio_info(gpio_rise_loc(ng)+1, 1);
                gpio_x(4+(ng-1)*4, 1) = gpio_info(gpio_rise_loc(ng)+1, 1);

                gpio_y(1+(ng-1)*4, 1) = gpio_info(gpio_rise_loc(ng)+1, 2);
                gpio_y(2+(ng-1)*4, 1) = gpio_info(gpio_rise_loc(ng), 2);
                gpio_y(3+(ng-1)*4, 1) = gpio_info(gpio_rise_loc(ng), 2);
                gpio_y(4+(ng-1)*4, 1) = gpio_info(gpio_rise_loc(ng)+1, 2);
            end
            % app.gpio_x = gpio_x;
            % app.gpio_y = gpio_y;
            % gpio_lim_loc{nc, 1} = find(gpio_y(:, 1) == 0); % location of gpio voltage ==0 in gpio_y
            % gpio_lim_loc{nc, 2} = find(gpio_y(:, 1)); % location of gpio voltage ~=0 in gpio_y
        end

        function [peak_info] = PlotPeaks(app, roi_lowpasseddata, rn, roi_n)
            roidata = app.ROIdata;
            recording_rawdata = roidata{rn, 2};
            peakinfo_row = find(strcmp(app.peakinfo_row_name, roidata{rn, 5}.Properties.RowNames)); % specifi which type of peak info will be used: raw, lowpassed, smoothed

            roi_name = roidata{rn,5}.Properties.VariableNames{roi_n}; % roi name ('C0, C1...')
            roi_col_loc_data = find(strcmp(roi_name, recording_rawdata.Properties.VariableNames)); % the column number of this roi in recording_rawdata (ROI_table)
            roi_col_loc_cal = find(strcmp(roi_name, roidata{rn, 5}.Properties.VariableNames)); % the column number of this roi in calculated peak-related info
            roi_col_data = recording_rawdata{:, roi_col_loc_data}; % roi data
            % peak_time_loc = roidata{rn, 5}{1, (roi_col_loc_cal)}{:, :}.('Peak_loc_s_'); % peak_loc as time
            % peak_value = roidata{rn, 5}{1, (roi_col_loc_cal)}{:, :}.('Peak_mag'); % peak magnitude

            % roi_col_data_lowpassed = recording_lowpassed{:, roi_col_loc_data}; % roi data
            peak_time_loc_lowpassed = roidata{rn, 5}{peakinfo_row, (roi_col_loc_cal)}{:, :}.('Peak_loc_s_'); % peak_loc as time
            peak_value_lowpassed = roidata{rn, 5}{peakinfo_row, (roi_col_loc_cal)}{:, :}.('Peak_mag'); % peak magnitude

            peak_rise_turning_loc = roidata{rn, 5}{peakinfo_row, (roi_col_loc_cal)}{:, :}.('Rise_start_s_');
            peak_rise_turning_value = roi_lowpasseddata(roidata{rn, 5}{peakinfo_row, (roi_col_loc_cal)}{:, :}.('Rise_start'));
            peak_decay_turning_loc = roidata{rn, 5}{peakinfo_row, (roi_col_loc_cal)}{:, :}.('Decay_stop_s_');
            peak_decay_turning_value = roi_lowpasseddata(roidata{rn, 5}{peakinfo_row, (roi_col_loc_cal)}{:, :}.('Decay_stop'));

            peak_info(:, 1) = peak_time_loc_lowpassed;
            peak_info(:, 2) = peak_value_lowpassed;
            peak_info(:, 3) = peak_rise_turning_loc;
            peak_info(:, 4) = peak_rise_turning_value;
            peak_info(:, 5) = peak_decay_turning_loc;
            peak_info(:, 6) = peak_decay_turning_value;

            % thresh_data = recording_thresh{:, roi_col_loc_data};
        end
        
        function [trig_plot, peak_info_trig_plot] = PlotTriggerResponse(app, rn, roi_n, gpio_train_start_time, gpio_train_end_time, peak_info) % align all stimulation on x-axis and plot all calcium signal
            roidata = app.ROIdata;
            pre_stimuli_time = gpio_train_start_time-app.pre_stimuli_duration; % plot from 3s before stimuli start. The "first GPIO stimuli"
            post_stimuli_time = gpio_train_end_time+app.post_stimuli_duration; % plot until 6s after stimuli end
            plot_duration = post_stimuli_time-pre_stimuli_time; % duration of plot

            recording_rawdata = roidata{rn, 2};
            recording_timeinfo = recording_rawdata{:, 1}; % array not table
            roi_name = roidata{rn,5}.Properties.VariableNames{roi_n}; % roi name ('C0, C1...')
            roi_col_loc_data = find(strcmp(roi_name, recording_rawdata.Properties.VariableNames)); % the column number of this roi in recording_rawdata (ROI_table)
            roi_col_data = recording_rawdata{:, roi_col_loc_data}; % roi data
            recording_fr = round(1/(recording_timeinfo(2)-recording_timeinfo(1)));
            roi_col_data_lowpass = lowpass(roi_col_data, app.lowpass_fpass,recording_fr);

            hold(app.UIAxes_triggered, 'on')
            for tn = 1:length(pre_stimuli_time) % number of stimulation trains
                [val_min, idx_min] = min(abs(recording_timeinfo-pre_stimuli_time(tn)));
                [val_max, idx_max] = min(abs(recording_timeinfo-post_stimuli_time(tn)));
                trig_plot{tn}(:, 1) = recording_timeinfo(idx_min:idx_max)-gpio_train_start_time(tn);

                idx_min_base = idx_min+recording_fr*(app.pre_stimuli_duration-app.baseline_duration); % loc of first data point of "baseline_duration" before stimulation
                idx_max_base = find((recording_timeinfo-gpio_train_start_time(tn))<0, 1, 'last'); % loc of last data point of "baseline_duration" before stimulation
                roi_col_data_base = mean(roi_col_data(idx_min_base:idx_max_base)); % baseline before 'tn' stimulation
                trig_plot{tn}(:, 2) = roi_col_data(idx_min:idx_max)-roi_col_data_base; % rawdata-baseline
                trig_plot{tn}(:, 3) = roi_col_data_lowpass(idx_min:idx_max)-roi_col_data_base; % lowpassData-baseline
                % roi_col_data_lowpassed_trig_plot = roi_col_data_lowpassed(idx_min:idx_max);

                peak_loc=find(peak_info(:,1)>=pre_stimuli_time(tn) & peak_info(:,1)<=post_stimuli_time(tn)); % peak(s) between pre_stimuli_time(tn) and post_stimuli_time(tn)
                rise_loc=find(peak_info(:,3)>=pre_stimuli_time(tn) & peak_info(:,3)<=post_stimuli_time(tn)); % rise(s) between pre_stimuli_time(tn) and post_stimuli_time(tn)
                decay_loc=find(peak_info(:,5)>=pre_stimuli_time(tn) & peak_info(:,5)<=post_stimuli_time(tn)); % decay(s) between pre_stimuli_time(tn) and post_stimuli_time(tn)

                if ~isempty(peak_loc)
                    peak_info_trig_plot{tn, 1}(:,1) = peak_info(rise_loc, 1)-gpio_train_start_time(tn); % peak time aligned to single triggered response
                    peak_info_trig_plot{tn, 1}(:,2) = peak_info(rise_loc, 2)-roi_col_data_base; % peak value aligned to single triggered response
                else
                    peak_info_trig_plot{tn, 1} = [];
                end
                if ~isempty(rise_loc)
                    peak_info_trig_plot{tn, 2}(:,1) = peak_info(rise_loc, 3)-gpio_train_start_time(tn); % rise time aligned to single triggered response
                    peak_info_trig_plot{tn, 2}(:,2) = peak_info(rise_loc, 4)-roi_col_data_base; % rise value aligned to single triggered response
                else
                    peak_info_trig_plot{tn, 1} = [];
                end
                if ~isempty(decay_loc)
                    peak_info_trig_plot{tn, 3}(:,1) = peak_info(rise_loc, 5)-gpio_train_start_time(tn); % decay time aligned to single triggered response
                    peak_info_trig_plot{tn, 3}(:,2) = peak_info(rise_loc, 6)-roi_col_data_base; % decay value aligned to single triggered response
                else
                    peak_info_trig_plot{tn, 1} = [];
                end

                plot(app.UIAxes_triggered, trig_plot{tn}(:, 1), trig_plot{tn}(:, 2), 'k'); % plot raw data sweeps
                plot(app.UIAxes_triggered, trig_plot{tn}(:, 1), trig_plot{tn}(:, 3), 'm')
            end
            hold(app.UIAxes_triggered, 'off')
        end
        
        function [tag_info, array_discard] = addtags(app, rn, roi_n, tn)
            rec_name = app.RecordingnameLabel.Text;
            roi_name = app.ROIdata{rn,5}.Properties.VariableNames{roi_n}; % roi name ('C0, C1...')
            stim_order = tn;
            mouseID = app.MouseIDEditField.Value;
            sex = app.SexDropDown.Value;
            DOB = app.DOBDatePicker.Value; % date of birth
            DOI = app.DOIDatePicker.Value; % date of virus injection
            DOE = app.DOEDatePicker.Value; % date of experiment
            
            if isnat(DOE) 
                transfection_day = 0;
                mouseAge = 0;
            else
                if isnat(DOI)
                    transfection_day = 0;
                else
                    transfection_day = caldiff([DOI; DOE], 'days'); % days of transfection
                    transfection_day = split(transfection_day, 'days'); % transfection time (from injection to experiment)
                end
                if isnat(DOB)
                    mouseAge = 0;
                else
                    mouseAge = caldiff([DOB; DOE], 'days'); % age in days
                    mouseAge = split(mouseAge, 'days'); % mouse age in days when it's sacrificed
                end
            end
            app.EditField_transfection_day.Value = transfection_day;
            app.EditField_age.Value = mouseAge;

            weight = app.WeightgEditField.Value; % unit: g

            if ~strcmp('Other', app.CalciumIndicatorDropDown.Value)
                CaIndicator = app.CalciumIndicatorDropDown.Value;
            else
                CaIndicator = app.EditField_CaIn.Value;
            end

            if ~strcmp('Other', app.OptoactuatorDropDown.Value)
                OptoActuator = app.OptoactuatorDropDown.Value;
            else
                OptoActuator = app.EditField_opto_actuator.Value;
            end

            if ~strcmp('Custom', app.RecordingFrequencyHzDropDown.Value)
                RecFrequency = str2num(app.RecordingFrequencyHzDropDown.Value);
            else
                RecFrequency = app.EditField_record_fq.Value;
            end

            if ~strcmp('Other', app.PartofneuronDropDown.Value)
                NeuronPart = app.PartofneuronDropDown.Value;
            else
                NeuronPart = app.EditField_neuron_part.Value;
            end

            if ~strcmp('Other', app.StimulationDropDown.Value)
                stim = app.StimulationDropDown.Value;
            else
                stim = app.EditField_stim.Value;
            end

            if ~strcmp('Custom', app.StimulationdurationsDropDown.Value)
                stim_duration = app.StimulationdurationsDropDown.Value;
                if strcmp('None', stim_duration)
                    stim_duration = [];
                else
                    stim_duration = str2num(stim_duration);
                end
            else
                stim_duration = app.EditField_stim_duration.Value;
            end

            stable_base = app.StableBaselineDropDown.Value;

            isoflu = app.IsofluraneCheckBox.Value;
            if strcmp(app.ConcentrationDropDown.Value, 'Concentration')
                isoflu_con = app.EditField_IsoCon.Value;
            else
                isoflu_con = [];
            end

            chloralose = app.ChloraloseCheckBox.Value;

            induce_transient = app.InduceCalciumtransientCheckBox.Value;
            supress_transient = app.SupressCalciumtransientCheckBox.Value;
            rebound_transient = app.ReboundCalciumtransientafterstimulationCheckBox.Value;

            Notes = app.NotesTextArea.Value;
            
            tag_info = {rec_name, roi_name, stim_order, mouseID, sex,...
                DOB, DOI, DOE, transfection_day, mouseAge,...
                weight, CaIndicator, OptoActuator, RecFrequency, NeuronPart,...
                stim, stim_duration, stable_base, isoflu, isoflu_con,...
                chloralose, induce_transient, supress_transient, rebound_transient, Notes};
            
                         % ==========
            %  roirawdata = app.ROIdata{app.rn, 2};
            %  roi_num_all = size(roirawdata, 2)-1; % total roi number in recording-app.rn
            %  roi_names = roirawdata.Properties.VariableNames(2:end);
            % if size(app.ROIdata, 2) < 7 % if there is no discard info in any recordings
            % 	array_discard = zeros(1, roi_num_all);
            % 	T_discard = array2table(array_discard);
            % 	T_discard.Properties.VariableNames = roi_names;
            % else
            % 	if isempty(app.ROIdata{rn, 7}) % if there is no discard info in current recording
            % 		array_discard = zeros(1, roi_num_all);
            % 		T_discard = array2table(array_discard);
            % 		T_discard.Properties.VariableNames = roi_names;
            % 	end
            % end
            % T_discard(roi_n).Variables = app.DiscardthisROICheckBox.Value;

            roirawdata = app.ROIdata{app.rn, 2};
            roi_num_all = size(roirawdata, 2)-1; % total roi number in recording-app.rn
            if size(app.ROIdata, 2 < 7) % if there is no discard info in any recordings
                array_discard = zeros(1, roi_num_all);
            else
            	if isempty(app.ROIdata{rn, 7}) % if there is no discard info in current recording
            	   array_discard = zeros(1, roi_num_all);
                else
                    array_discard = app.ROIdata{rn, 7}.Variables;
            	end
            end
            array_discard(roi_n) = app.DiscardthisROICheckBox.Value;
        end
        
        function results = readtags(app, tag_info, rn, roi_n, tn)
            row_num_all = size(tag_info, 1); % total row number of tags in recording-app.rn
            roi_num_all = size(app.ROIdata{app.rn, 2}, 2)-1; % total roi number in recording-app.rn
            stim_num_all = row_num_all/roi_num_all; % number of stimulation in this recording
            row_num = (roi_n-1)*stim_num_all+tn; % number of row containing tags for specific ROI and stim-order

            rec_name_col = 1;
            roi_name_col = 2;
            stim_order_col = 3;
            mouseID_col = 4;
            sex_col = 5;
            DOB_col = 6;
            DOI_col = 7;
            DOE_col = 8;
            transfection_day_col = 9;
            mouseAge_col = 10;
            weight_col = 11;
            CaIndicator_col = 12;
            OptoActuator_col = 13; 
            RecFrequency_col = 14;
            NeuronPart_col = 15;
            stim_col = 16;
            stim_duration_col = 17;
            stable_base_col = 18;
            isoflu_col = 19;
            isoflu_con_col = 20;
            chloralose_col = 21;
            induce_transient_col = 22;
            supress_transient_col = 23;
            rebound_transient_col = 24;
            Notes_col = 25;

            app.MouseIDEditField.Value = tag_info{row_num, mouseID_col};
            app.SexDropDown.Value = tag_info{row_num, sex_col};
            app.DOBDatePicker.Value = tag_info{row_num, DOB_col};
            app.DOIDatePicker.Value = tag_info{row_num, DOI_col};
            app.DOEDatePicker.Value = tag_info{row_num, DOE_col};
            app.EditField_transfection_day.Value = tag_info{row_num, transfection_day_col};
            app.EditField_age.Value = tag_info{row_num, mouseAge_col};
            app.WeightgEditField.Value = tag_info{row_num, weight_col};

            CaIndicator = tag_info{row_num, CaIndicator_col};
            CaIndicator_check = cellfun(@(x) strcmp(CaIndicator, x), app.CalciumIndicatorDropDown.Items, 'UniformOutput', false);
            CaIndicator_check = double(cell2mat(CaIndicator_check));
            if ~isempty(find(CaIndicator_check))
            	app.CalciumIndicatorDropDown.Value = CaIndicator;
            else
            	app.CalciumIndicatorDropDown.Value = 'Other';
            	app.EditField_CaIn.Enable = 1;
            	app.EditField_CaIn.Value = CaIndicator;
            end

            OptoActuator = tag_info{row_num, OptoActuator_col};
            OptoActuator_check = cellfun(@(x) strcmp(OptoActuator, x), app.OptoactuatorDropDown.Items, 'UniformOutput', false);
            OptoActuator_check = double(cell2mat(OptoActuator_check));
            if ~isempty(find(OptoActuator_check))
            	app.OptoactuatorDropDown.Value = OptoActuator;
            else
            	app.OptoactuatorDropDown.Value = 'Other';
            	app.EditField_opto_actuator.Enable = 1;
            	app.EditField_opto_actuator.Value = OptoActuator;
            end

            RecFrequency = tag_info{row_num, RecFrequency_col};
            RecFrequency_check = cellfun(@(x) strcmp(num2str(RecFrequency), x), app.RecordingFrequencyHzDropDown.Items, 'UniformOutput', false);
            RecFrequency_check = double(cell2mat(RecFrequency_check));
            if ~isempty(find(RecFrequency_check))
            	app.RecordingFrequencyHzDropDown.Value = num2str(RecFrequency);
            else
            	app.RecFrequencyDropDown.Value = 'Custom';
            	app.EditField_record_fq.Enable = 1;
            	app.EditField_record_fq.Value = RecFrequency;
            end

            NeuronPart = tag_info{row_num, NeuronPart_col};
            NeuronPart_check = cellfun(@(x) strcmp(NeuronPart, x), app.PartofneuronDropDown.Items, 'UniformOutput', false);
            NeuronPart_check = double(cell2mat(NeuronPart_check));
            if ~isempty(find(NeuronPart_check))
            	app.PartofneuronDropDown.Value = NeuronPart;
            else
            	app.NeuronPartDropDown.Value = 'Other';
            	app.EditField_neuron_part.Enable = 1;
            	app.EditField_neuron_part.Value = NeuronPart;
            end

            stim = tag_info{row_num, stim_col};
            stim_check = cellfun(@(x) strcmp(stim, x), app.StimulationDropDown.Items, 'UniformOutput', false);
            stim_check = double(cell2mat(stim_check));
            if ~isempty(find(stim_check))
            	app.StimulationDropDown.Value = stim;
            else
            	app.StimulationdurationsDropDown.Value = 'Custom';
            	app.EditField_stim_duration.Enable = 1;
            	app.EditField_stim_duration.Value = stim;
            end

            stim_duration = tag_info{row_num, stim_duration_col};
            stim_duration_check = cellfun(@(x) strcmp(num2str(stim_duration), x), app.StimulationdurationsDropDown.Items, 'UniformOutput', false);
            stim_duration_check = double(cell2mat(stim_duration_check));
            if ~isempty(find(stim_duration_check))
            	app.StimulationdurationsDropDown.Value = num2str(stim_duration);
            else
                if isempty(stim_duration)
                    app.StimulationdurationsDropDown.Value = 'None';
                else
                    app.StimulationdurationsDropDown.Value = 'Custom';
                    app.EditField_stim_duration.Enable = 1;
                    app.EditField_stim_duration.Value = stim_duration;
                end
            end

            app.StableBaselineDropDown.Value = tag_info{row_num, stable_base_col};
            app.IsofluraneCheckBox.Value = tag_info{row_num, isoflu_col};

            isoflu_con = tag_info{row_num, isoflu_con_col};
            if ~isempty(isoflu_con)
            	app.ConcentrationDropDown.Value = 'Concentration';
            else
            	app.ConcentrationDropDown.Value = 'Unknown';
            	app.EditField_IsoCon.Value = 0;
            end
            app.ChloraloseCheckBox.Value = tag_info{row_num, chloralose_col};

            app.InduceCalciumtransientCheckBox.Value = tag_info{row_num, induce_transient_col};
            app.SupressCalciumtransientCheckBox.Value = tag_info{row_num, supress_transient_col};
            app.ReboundCalciumtransientafterstimulationCheckBox.Value = tag_info{row_num, rebound_transient_col};

            app.NotesTextArea.Value = tag_info{row_num, Notes_col};
            
             % ==========
             roirawdata = app.ROIdata{app.rn, 2};
             roi_names = roirawdata.Properties.VariableNames(2:end);
            if size(app.ROIdata, 2) < 7 % if there is no discard info in any recordings
            	array_discard = zeros(1, roi_num_all);
            	T_discard = array2table(array_discard);
            	T_discard.Properties.VariableNames = roi_names;
            else
            	if isempty(app.ROIdata{rn, 7}) % if there is no discard info in current recording
            	   array_discard = zeros(1, roi_num_all);
            	   T_discard = array2table(array_discard);
            	   T_discard.Properties.VariableNames = roi_names;
                else
                   T_discard = app.ROIdata{rn, 7};                     
            	end
            end
            	app.DiscardthisROICheckBox.Value = T_discard{1, roi_n};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton_ROIdata_2
        function BrowseButton_ROIdata_2Pushed(app, event)
            [ROIdata_file, folder_ROIdata] = uigetfile([app.folder_ROIdata, '\*.mat'], 'Choose a mat file containing ROIdata'); % input file path info. Choose a motion corrected file
            if isequal(ROIdata_file,0) || isequal(folder_ROIdata,0)
               disp('User clicked Cancel.')
            else
               ROIdataFilepath = fullfile(app.folder_ROIdata, ROIdata_file);
               app.folder_ROIdata = folder_ROIdata;
               app.ROIdatafileEditField_2.Value = ROIdataFilepath;
               matObj = matfile(app.ROIdatafileEditField_2.Value);
               varlist = who(matObj);
               app.ROIdataVar = varlist{1};
               app.viarableloadedLabel_name_2.Text = app.ROIdataVar;
               
               % load ROI varible and plot the first ROI
               MatVar = load(app.ROIdatafileEditField_2.Value); % load mat file
               VarNames = fieldnames(MatVar); % get a list of variable names
               app.ROIdata = MatVar.(VarNames{1}); % assign the first variable (the only one) to ROIdata
               app.ListBox.Items = app.ROIdata(:, 1);
               app.ListBox.Value = app.ListBox.Items(app.rn);
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            cla(app.UIAxes, 'reset');
            cla(app.UIAxes_triggered, 'reset');
            cla(app.UIAxes_triggered_single, 'reset')
            app.RecordingnameLabel.Text = app.ListBox.Value;
            app.rn = find(strcmp(app.ListBox.Items, app.ListBox.Value));
            app.roi_n = 1;

            if ~isempty(app.ROIdata)
                if ~isempty(app.ROIdata{app.rn, 3}) % if there is a stimulation
                    stimulation_type = app.ROIdata{app.rn, 3}{1}; % read GPIO name
                    switch stimulation_type
                    case 'OG_LED'
                        app.stimulation_tag = 'LED'; 
                        app.StimulationDropDown.Value = app.StimulationDropDown.Items{2};
                        plot_gpio = 1;
                    case 'GPIO1'
                        app.stimulation_tag = 'Airpuff';
                        app.StimulationDropDown.Value = app.StimulationDropDown.Items{3};
                        plot_gpio = 1;
                    otherwise
                        app.stimulation_tag = 'Other';
                        app.StimulationDropDown.Value = app.StimulationDropDown.Items{4};
                        app.EditField_stim.Enable = 1;
                        plot_gpio = 0;
                    end
                else
                    app.stimulation_tag = 'None';
                    app.StimulationDropDown.Value = app.StimulationDropDown.Items{1};
                    app.StimulationdurationsDropDown.Value = app.StimulationdurationsDropDown.Items{1};
                    plot_gpio = 0;
                end

                [recording_timeinfo, roi_col_data, roi_col_data_lowpassed] = PlotRoi(app, app.ROIdata, app.rn, app.roi_n);
                [peak_info] = PlotPeaks(app, roi_col_data_lowpassed, app.rn, app.roi_n); % peak_info 
                roidata = app.ROIdata;
                
                plot(app.UIAxes, recording_timeinfo, roi_col_data, 'k'); % plot original data
                hold(app.UIAxes, 'on')
                plot(app.UIAxes, recording_timeinfo, roi_col_data_lowpassed, 'm');
                plot(app.UIAxes, peak_info(:, 1), peak_info(:, 2), 'yo', 'linewidth', 2) %plot lowpassed data peak marks
                plot(app.UIAxes, peak_info(:, 3), peak_info(:, 4), '>b', peak_info(:, 5), peak_info(:, 6), '<b', 'linewidth', 2); % plot start and end of transient, turning point
                % hold(app.UIAxes, 'off')

                if plot_gpio == 1
                    app.PreviousStimulationButton.Enable = 1;
                    app.NextStimulationButton.Enable = 1;
                    app.tn = 1;
                    ylim_gpio = ylim(app.UIAxes);
                    [gpio_x, gpio_y, gpio_train_start_time, gpio_train_end_time] = extract_gpio_into(app, app.ROIdata, app.rn);
                    gpio_y(find(gpio_y)) = ylim_gpio(2); % change gpio non-zero signal to max ylim for plot
                    gpio_y(find(gpio_y==0)) = ylim_gpio(1); % change gpio==0 to min ylim for plot
                    stimuli_method = app.StimulationDropDown.Value;
                    stimuli_duration = app.StimulationdurationsDropDown.Value;
                    app.UIAxes_triggered.Title.String = stimuli_method;
                    patch(app.UIAxes, gpio_x(:, 1), gpio_y(:, 1), 'cyan', 'EdgeColor', 'none', 'FaceAlpha', 0.8);
                    hold(app.UIAxes, 'off')

                    % pre_stimuli_time = gpio_train_start_time-app.pre_stimuli_duration; % plot from 3s before stimuli start. The "first GPIO stimuli"
                    % post_stimuli_time = gpio_train_end_time+app.post_stimuli_duration; % plot until 6s after stimuli end
                    % plot_duration = post_stimuli_time-pre_stimuli_time; % duration of plot

                    first_gpio_train_start_loc = find(gpio_x==gpio_train_start_time(1), 1); % location of first train starts
                    first_gpio_train_end_loc = find(gpio_x==gpio_train_end_time(1), 1, 'last'); % location of first train ends
                    app.gpio_x_trig_plot = gpio_x(first_gpio_train_start_loc:first_gpio_train_end_loc, 1); % gpio_x of the first train
                    app.gpio_x_trig_plot = app.gpio_x_trig_plot-app.gpio_x_trig_plot(1, 1); % gpio_x starts from 0
                    app.gpio_y_trig_plot = gpio_y(first_gpio_train_start_loc:first_gpio_train_end_loc); % gpio_y of the first train

                    [app.trig_plot, app.peak_trig_plot] = PlotTriggerResponse(app, app.rn, app.roi_n, gpio_train_start_time, gpio_train_end_time, peak_info); 

                    lim_gpio_trig = axis(app.UIAxes_triggered);
                    ylim_gpio = unique(app.gpio_y_trig_plot, 'sorted');
                    app.gpio_y_trig_plot(find(app.gpio_y_trig_plot==ylim_gpio(1))) = lim_gpio_trig(3); % change gpio low to min ylim for plot
                    app.gpio_y_trig_plot(find(app.gpio_y_trig_plot==ylim_gpio(2))) = lim_gpio_trig(4); % change gpio high to max ylim for plot
                    patch(app.UIAxes_triggered, app.gpio_x_trig_plot, app.gpio_y_trig_plot, 'cyan', 'EdgeColor', 'none', 'FaceAlpha', 0.8)
                    app.stimuli_num_all = size(app.peak_trig_plot, 1);
                    app.UIAxes_triggered_single.Title.String = ['Stimulation ', num2str(app.tn), '/', num2str(app.stimuli_num_all)];
                    patch(app.UIAxes_triggered_single, app.gpio_x_trig_plot, app.gpio_y_trig_plot, 'cyan', 'EdgeColor', 'none', 'FaceAlpha', 0.8)
                    hold(app.UIAxes_triggered_single, 'on')
                    plot(app.UIAxes_triggered_single, app.trig_plot{app.tn}(:, 1), app.trig_plot{app.tn}(:, 2), 'k')
                    plot(app.UIAxes_triggered_single, app.trig_plot{app.tn}(:, 1), app.trig_plot{app.tn}(:, 3), 'm')
                    if ~isempty(app.peak_trig_plot{app.tn, 1})
                        plot(app.UIAxes_triggered_single, app.peak_trig_plot{app.tn, 1}(:, 1), app.peak_trig_plot{app.tn, 1}(:, 2), 'yo', 'linewidth', 2) % plot peaks on lowpassed data in single triggerd response Axes
                        plot(app.UIAxes_triggered_single, app.peak_trig_plot{app.tn, 2}(:, 1), app.peak_trig_plot{app.tn, 2}(:, 2), '>b', app.peak_trig_plot{app.tn, 3}(:, 1), app.peak_trig_plot{app.tn, 3}(:, 2), '<b', 'linewidth', 2) % plot rise and decay in single plot axes
                    end
                    hold(app.UIAxes_triggered_single, 'off')
                else
                    app.PreviousStimulationButton.Enable = 0;
                    app.NextStimulationButton.Enable = 0;
                    app.stimuli_num_all = [];
                end

                app.roi_num_all = size(roidata{app.rn, 2}, 2)-1; % total roi number in recording-app.rn
                if isempty(app.tag_info)
                    app.tag_info = cell(size(roidata, 1)); % pre-allocate. each recording has a cell for tag in tag_info
                end
                if isempty(app.tag_info{app.rn})
                    tag_table_col_size = size(app.TagName, 2); % total column number for tag storage
                    if plot_gpio == 1
                        app.tag_info{app.rn} = cell(app.roi_num_all*app.stimuli_num_all, tag_table_col_size); % pre-allocate. each recording cell has all stim from all roi
                    else
                        app.tag_info{app.rn} = cell(app.roi_num_all, tag_table_col_size);
                    end                
                end
                
                if isempty(app.stimuli_num_all) % if no stimulation
                    tag_info_row = app.roi_n; % use roi number as row number
                else
                    tag_info_row = (app.roi_n-1)*app.stimuli_num_all+app.tn; % row of tags for specific ROI and triggered signal
                end
                if size(app.ROIdata, 2) >= 6 && istable(app.ROIdata{app.rn, 6})% if there is tag information stored in app.ROIdata
                    tagtable_info = app.ROIdata{app.rn, 6}.Variables;
                    app.tag_info{app.rn} = tagtable_info;
                    if ~isempty(tagtable_info{tag_info_row, 1})
                        readtags(app, tagtable_info, app.rn, app.roi_n, app.tn);
                        % app.tag_info{app.rn}(tag_info_row, :) = tagtable_info(tag_info_row, :);
                        app.NoTagInfoLabel.Text = 'Tag Info Exists';
                        app.NoTagInfoLabel.FontColor = [0.00,0.45,0.74];
                    else
                        app.NoTagInfoLabel.Text = 'No Tag Info';
                        app.NoTagInfoLabel.FontColor = [1.00,0.00,1.00];
                        % if ~isempty(app.tag_info{app.rn}{tag_info_row, 1})
                        %     readtags(app, app.tag_info{app.rn}, app.roi_n, app.tn);
                        % end
                    end
                else
                    tag_table = app.tag_info{app.rn}; % give proper structure to tag table
                    tag_table = cell2table(tag_table); % convert cell to table array
                    tag_table.Properties.VariableNames = app.TagName; % add variable names
                    app.ROIdata{app.rn, 6} = tag_table;                    
                    if ~isempty(app.tag_info{app.rn}{tag_info_row, 1})
                        readtags(app, app.tag_info{app.rn}, app.rn, app.roi_n, app.tn);
                    end
                end
                if isempty(app.discard_tag)
                	app.discard_tag = cell(size(roidata,1)); % pre-allocate. Each recording has a cell for discarding ROIs
                end
                if isempty(app.discard_tag{app.rn})
                    roirawdata = app.ROIdata{app.rn, 2};
                    roi_num_all = size(roirawdata, 2)-1; % total roi number in recording-app.rn
                    roinames = roirawdata.Properties.VariableNames(2:end);
                    discard_tag_array = zeros(1, roi_num_all);
                    discard_tag_table = array2table(discard_tag_array);
                    discard_tag_table.Properties.VariableNames = roinames;
                    app.discard_tag{app.rn} = discard_tag_table;
                end
                app.discard_tag{app.rn}{1, app.roi_n} = app.DiscardthisROICheckBox.Value;
            end
        end

        % Button pushed function: PreviousROIButton
        function PreviousROIButtonPushed(app, event)
            % tag_info_row = (app.roi_n-1)*app.stimuli_num_all+app.tn; % row of tags for specific ROI and triggered signal
            % if isempty(app.tag_info{app.rn})
            %     tag_table_col_size = size(app.TagName, 2); % total column number for tag storage
            %     app.tag_info{app.rn} = cell(app.roi_num_all*app.stimuli_num_all, tag_table_col_size); % pre-allocate. each recording cell has all stim from all roi
            % end
            % [app.tag_info{app.rn}(tag_info_row, :)] = addtags(app, app.rn, app.roi_n, app.tn); % store unsaved tags to tag_info
            
            if app.rn == 1 && app.roi_n == 1
                display('This is the first ROI, no previous one')
            else
                if app.roi_n ~= 1
                    new_recording = 0;
                    app.roi_n = app.roi_n-1;
                    if strcmp('None', app.stimulation_tag) || strcmp('Other', app.stimulation_tag)
                        plot_gpio = 0;
                    else
                        plot_gpio = 1;
                    end
                else
                    new_recording =1;
                    app.rn = app.rn - 1;
                    app.RecordingnameLabel.Text = app.ROIdata{app.rn, 1};
                    recording_rawdata = app.ROIdata{app.rn, 2};
                    [recording_rawdata, recording_time, roi_num_all] = ROI_calc_plot(recording_rawdata);
                    app.roi_n = roi_num_all;

                    if ~isempty(app.ROIdata{app.rn, 3}) % if there is a stimulation
                        stimulation_type = app.ROIdata{app.rn, 3}{1}; % read GPIO name
                        switch stimulation_type
                        case 'OG_LED'
                            app.stimulation_tag = 'LED'; 
                            app.StimulationDropDown.Value = app.StimulationDropDown.Items{2};
                            plot_gpio = 1;
                        case 'GPIO1'
                            app.stimulation_tag = 'Airpuff';
                            app.StimulationDropDown.Value = app.StimulationDropDown.Items{3};
                            plot_gpio = 1;
                        otherwise
                            app.stimulation_tag = 'Other';
                            app.StimulationDropDown.Value = app.StimulationDropDown.Items{4};
                            app.EditField_stim.Enable = 1;
                            plot_gpio = 0;
                        end
                    else
                        app.stimulation_tag = 'None';
                        app.StimulationDropDown.Value = app.StimulationDropDown.Items{1};
                        app.StimulationdurationsDropDown.Value = app.StimulationdurationsDropDown.Items{1};
                        plot_gpio = 0;
                    end
                end
                cla(app.UIAxes, 'reset');
                cla(app.UIAxes_triggered, 'reset');
                cla(app.UIAxes_triggered_single, 'reset')

                [recording_timeinfo, roi_col_data, roi_col_data_lowpassed] = PlotRoi(app, app.ROIdata, app.rn, app.roi_n);
                [peak_info] = PlotPeaks(app, roi_col_data_lowpassed, app.rn, app.roi_n); % peak_info 
                roidata = app.ROIdata;
                
                plot(app.UIAxes, recording_timeinfo, roi_col_data, 'k'); % plot original data
                hold(app.UIAxes, 'on')
                plot(app.UIAxes, recording_timeinfo, roi_col_data_lowpassed, 'm');
                plot(app.UIAxes, peak_info(:, 1), peak_info(:, 2), 'yo', 'linewidth', 2) %plot lowpassed data peak marks
                plot(app.UIAxes, peak_info(:, 3), peak_info(:, 4), '>b', peak_info(:, 5), peak_info(:, 6), '<b', 'linewidth', 2); % plot start and end of transient, turning point
                % hold(app.UIAxes, 'off')

                if plot_gpio == 1
                    app.PreviousStimulationButton.Enable = 1;
                    app.NextStimulationButton.Enable = 1;                    
                    app.tn = 1;
                    ylim_gpio = ylim(app.UIAxes);
                    [gpio_x, gpio_y, gpio_train_start_time, gpio_train_end_time] = extract_gpio_into(app, app.ROIdata, app.rn);
                    gpio_y(find(gpio_y)) = ylim_gpio(2); % change gpio non-zero signal to max ylim for plot
                    gpio_y(find(gpio_y==0)) = ylim_gpio(1); % change gpio==0 to min ylim for plot
                    stimuli_method = app.StimulationDropDown.Value;
                    stimuli_duration = app.StimulationdurationsDropDown.Value;
                    app.UIAxes_triggered.Title.String = stimuli_method;
                    patch(app.UIAxes, gpio_x(:, 1), gpio_y(:, 1), 'cyan', 'EdgeColor', 'none', 'FaceAlpha', 0.8);
                    hold(app.UIAxes, 'off')

                    first_gpio_train_start_loc = find(gpio_x==gpio_train_start_time(1), 1); % location of first train starts
                    first_gpio_train_end_loc = find(gpio_x==gpio_train_end_time(1), 1, 'last'); % location of first train ends
                    app.gpio_x_trig_plot = gpio_x(first_gpio_train_start_loc:first_gpio_train_end_loc, 1); % gpio_x of the first train
                    app.gpio_x_trig_plot = app.gpio_x_trig_plot-app.gpio_x_trig_plot(1, 1); % gpio_x starts from 0
                    app.gpio_y_trig_plot = gpio_y(first_gpio_train_start_loc:first_gpio_train_end_loc); % gpio_y of the first train

                    [app.trig_plot, app.peak_trig_plot] = PlotTriggerResponse(app, app.rn, app.roi_n, gpio_train_start_time, gpio_train_end_time, peak_info); 

                    lim_gpio_trig = axis(app.UIAxes_triggered);
                    ylim_gpio = unique(app.gpio_y_trig_plot, 'sorted');
                    app.gpio_y_trig_plot(find(app.gpio_y_trig_plot==ylim_gpio(1))) = lim_gpio_trig(3); % change gpio low to min ylim for plot
                    app.gpio_y_trig_plot(find(app.gpio_y_trig_plot==ylim_gpio(2))) = lim_gpio_trig(4); % change gpio high to max ylim for plot
                    patch(app.UIAxes_triggered, app.gpio_x_trig_plot, app.gpio_y_trig_plot, 'cyan', 'EdgeColor', 'none', 'FaceAlpha', 0.8)
                    app.stimuli_num_all = size(app.peak_trig_plot, 1);
                    app.UIAxes_triggered_single.Title.String = ['Stimulation ', num2str(app.tn), '/', num2str(app.stimuli_num_all)];
                    patch(app.UIAxes_triggered_single, app.gpio_x_trig_plot, app.gpio_y_trig_plot, 'cyan', 'EdgeColor', 'none', 'FaceAlpha', 0.8)
                    hold(app.UIAxes_triggered_single, 'on')
                    plot(app.UIAxes_triggered_single, app.trig_plot{app.tn}(:, 1), app.trig_plot{app.tn}(:, 2), 'k')
                    plot(app.UIAxes_triggered_single, app.trig_plot{app.tn}(:, 1), app.trig_plot{app.tn}(:, 3), 'm')
                    if ~isempty(app.peak_trig_plot{app.tn, 1})
                        plot(app.UIAxes_triggered_single, app.peak_trig_plot{app.tn, 1}(:, 1), app.peak_trig_plot{app.tn, 1}(:, 2), 'yo', 'linewidth', 2) % plot peaks on lowpassed data in single triggerd response Axes
                        plot(app.UIAxes_triggered_single, app.peak_trig_plot{app.tn, 2}(:, 1), app.peak_trig_plot{app.tn, 2}(:, 2), '>b', app.peak_trig_plot{app.tn, 3}(:, 1), app.peak_trig_plot{app.tn, 3}(:, 2), '<b', 'linewidth', 2) % plot rise and decay in single plot axes
                    end
                    hold(app.UIAxes_triggered_single, 'off')
                else
                    app.PreviousStimulationButton.Enable = 0;
                    app.NextStimulationButton.Enable = 0;  
                    app.stimuli_num_all = [];
                end
                app.roi_num_all = size(roidata{app.rn, 2}, 2)-1; % total roi number in recording-app.rn
                if isempty(app.tag_info)
                    app.tag_info = cell(size(roidata, 1)); % pre-allocate. each recording has a cell for tag in tag_info
                end
                if isempty(app.tag_info{app.rn})
                    tag_table_col_size = size(app.TagName, 2); % total column number for tag storage
                    if plot_gpio == 1
                        app.tag_info{app.rn} = cell(app.roi_num_all*app.stimuli_num_all, tag_table_col_size); % pre-allocate. each recording cell has all stim from all roi
                    else
                        app.tag_info{app.rn} = cell(app.roi_num_all, tag_table_col_size);
                    end                
                end

                if isempty(app.stimuli_num_all) % if no stimulation
                    tag_info_row = app.roi_n; % use roi number as row number
                else
                    tag_info_row = (app.roi_n-1)*app.stimuli_num_all+app.tn; % row of tags for specific ROI and triggered signal
                end
                if size(app.ROIdata, 2) >= 6 && istable(app.ROIdata{app.rn, 6})% if there is tag information stored in app.ROIdata
                    tagtable_info = app.ROIdata{app.rn, 6}.Variables;
                    app.tag_info{app.rn} = tagtable_info;
                    if ~isempty(tagtable_info{tag_info_row, 1})
                        readtags(app, tagtable_info, app.rn, app.roi_n, app.tn);
                        % app.tag_info{app.rn}(tag_info_row, :) = tagtable_info(tag_info_row, :);
                        app.NoTagInfoLabel.Text = 'Tag Info Exists';
                        app.NoTagInfoLabel.FontColor = [0.00,0.45,0.74];
                    else
                        app.NoTagInfoLabel.Text = 'No Tag Info';
                        app.NoTagInfoLabel.FontColor = [1.00,0.00,1.00];
                        % if ~isempty(app.tag_info{app.rn}{tag_info_row, 1})
                        %     readtags(app, app.tag_info{app.rn}, app.roi_n, app.tn);
                        % end
                    end
                else
                    tag_table = app.tag_info{app.rn}; % give proper structure to tag table
                    tag_table = cell2table(tag_table); % convert cell to table array
                    tag_table.Properties.VariableNames = app.TagName; % add variable names
                    app.ROIdata{app.rn, 6} = tag_table;                    
                    if ~isempty(app.tag_info{app.rn}{tag_info_row, 1})
                        readtags(app, app.tag_info{app.rn}, app.rn, app.roi_n, app.tn);
                    end
                end

                if isempty(app.discard_tag)
                	app.discard_tag = cell(size(roidata,1)); % pre-allocate. Each recording has a cell for discarding ROIs
                end
                if isempty(app.discard_tag{app.rn})
                    roirawdata = app.ROIdata{app.rn, 2};
                    roi_num_all = size(roirawdata, 2)-1; % total roi number in recording-app.rn
                    roinames = roirawdata.Properties.VariableNames(2:end);
                    discard_tag_array = zeros(1, roi_num_all);
                    discard_tag_table = array2table(discard_tag_array);
                    discard_tag_table.Properties.VariableNames = roinames;
                    app.discard_tag{app.rn} = discard_tag_table;
                end
                app.discard_tag{app.rn}{1, app.roi_n} = app.DiscardthisROICheckBox.Value;
            end
        end

        % Button pushed function: NextROIButton
        function NextROIButtonPushed(app, event)
            % tag_info_row = (app.roi_n-1)*app.stimuli_num_all+app.tn; % row of tags for specific ROI and triggered signal
            % if isempty(app.tag_info{app.rn})
            %     tag_table_col_size = size(app.TagName, 2); % total column number for tag storage
            %     app.tag_info{app.rn} = cell(app.roi_num_all*app.stimuli_num_all, tag_table_col_size); % pre-allocate. each recording cell has all stim from all roi
            % end
            % [app.tag_info{app.rn}(tag_info_row, :)] = addtags(app, app.rn, app.roi_n, app.tn); % store unsaved tags to tag_info
            
            recording_num_all = size(app.ROIdata, 1);
            recording_rawdata = app.ROIdata{app.rn, 2};
            [recording_rawdata, recording_time, roi_num_all] = ROI_calc_plot(recording_rawdata);

            if app.rn == recording_num_all && app.roi_n == roi_num_all
                display('This is the last ROI')
            else
                if app.roi_n ~= roi_num_all
                    app.roi_n = app.roi_n+1;
                    if strcmp('None', app.stimulation_tag) || strcmp('Other', app.stimulation_tag)
                        plot_gpio = 0;
                    else
                        plot_gpio = 1;
                    end
                else
                    app.rn = app.rn+1;
                    app.RecordingnameLabel.Text = app.ROIdata{app.rn, 1};
                    app.roi_n = 1;
                    if ~isempty(app.ROIdata{app.rn, 3}) % if there is a stimulation
                        stimulation_type = app.ROIdata{app.rn, 3}{1}; % read GPIO name
                        switch stimulation_type
                        case 'OG_LED'
                            app.stimulation_tag = 'LED'; 
                            app.StimulationDropDown.Value = app.StimulationDropDown.Items{2};
                            plot_gpio = 1;
                        case 'GPIO1'
                            app.stimulation_tag = 'Airpuff';
                            app.StimulationDropDown.Value = app.StimulationDropDown.Items{3};
                            plot_gpio = 1;
                        otherwise
                            app.stimulation_tag = 'Other';
                            app.StimulationDropDown.Value = app.StimulationDropDown.Items{4};
                            app.EditField_stim.Enable = 1;
                            plot_gpio = 0;
                        end
                    else
                        app.stimulation_tag = 'None';
                        app.StimulationDropDown.Value = app.StimulationDropDown.Items{1};
                        app.StimulationdurationsDropDown.Value = app.StimulationdurationsDropDown.Items{1};
                        plot_gpio = 0;
                    end
                end
                cla(app.UIAxes, 'reset');
                cla(app.UIAxes_triggered, 'reset');
                cla(app.UIAxes_triggered_single, 'reset')

                [recording_timeinfo, roi_col_data, roi_col_data_lowpassed] = PlotRoi(app, app.ROIdata, app.rn, app.roi_n);
                [peak_info] = PlotPeaks(app, roi_col_data_lowpassed, app.rn, app.roi_n); % peak_info 
                roidata = app.ROIdata;
                
                plot(app.UIAxes, recording_timeinfo, roi_col_data, 'k'); % plot original data
                hold(app.UIAxes, 'on')
                plot(app.UIAxes, recording_timeinfo, roi_col_data_lowpassed, 'm');
                plot(app.UIAxes, peak_info(:, 1), peak_info(:, 2), 'yo', 'linewidth', 2) %plot lowpassed data peak marks
                plot(app.UIAxes, peak_info(:, 3), peak_info(:, 4), '>b', peak_info(:, 5), peak_info(:, 6), '<b', 'linewidth', 2); % plot start and end of transient, turning point
                % hold(app.UIAxes, 'off')

                if plot_gpio == 1
                    app.PreviousStimulationButton.Enable = 1;
                    app.NextStimulationButton.Enable = 1;
                    app.tn = 1;
                    ylim_gpio = ylim(app.UIAxes);
                    [gpio_x, gpio_y, gpio_train_start_time, gpio_train_end_time] = extract_gpio_into(app, app.ROIdata, app.rn);
                    gpio_y(find(gpio_y)) = ylim_gpio(2); % change gpio non-zero signal to max ylim for plot
                    gpio_y(find(gpio_y==0)) = ylim_gpio(1); % change gpio==0 to min ylim for plot
                    stimuli_method = app.StimulationDropDown.Value;
                    stimuli_duration = app.StimulationdurationsDropDown.Value;
                    app.UIAxes_triggered.Title.String = stimuli_method;
                    patch(app.UIAxes, gpio_x(:, 1), gpio_y(:, 1), 'cyan', 'EdgeColor', 'none', 'FaceAlpha', 0.8);
                    hold(app.UIAxes, 'off')

                    first_gpio_train_start_loc = find(gpio_x==gpio_train_start_time(1), 1); % location of first train starts
                    first_gpio_train_end_loc = find(gpio_x==gpio_train_end_time(1), 1, 'last'); % location of first train ends
                    app.gpio_x_trig_plot = gpio_x(first_gpio_train_start_loc:first_gpio_train_end_loc, 1); % gpio_x of the first train
                    app.gpio_x_trig_plot = app.gpio_x_trig_plot-app.gpio_x_trig_plot(1, 1); % gpio_x starts from 0
                    app.gpio_y_trig_plot = gpio_y(first_gpio_train_start_loc:first_gpio_train_end_loc); % gpio_y of the first train

                    [app.trig_plot, app.peak_trig_plot] = PlotTriggerResponse(app, app.rn, app.roi_n, gpio_train_start_time, gpio_train_end_time, peak_info); 

                    lim_gpio_trig = axis(app.UIAxes_triggered);
                    ylim_gpio = unique(app.gpio_y_trig_plot, 'sorted');
                    app.gpio_y_trig_plot(find(app.gpio_y_trig_plot==ylim_gpio(1))) = lim_gpio_trig(3); % change gpio low to min ylim for plot
                    app.gpio_y_trig_plot(find(app.gpio_y_trig_plot==ylim_gpio(2))) = lim_gpio_trig(4); % change gpio high to max ylim for plot
                    patch(app.UIAxes_triggered, app.gpio_x_trig_plot, app.gpio_y_trig_plot, 'cyan', 'EdgeColor', 'none', 'FaceAlpha', 0.8)
                    app.stimuli_num_all = size(app.peak_trig_plot, 1);
                    app.UIAxes_triggered_single.Title.String = ['Stimulation ', num2str(app.tn), '/', num2str(app.stimuli_num_all)];
                    patch(app.UIAxes_triggered_single, app.gpio_x_trig_plot, app.gpio_y_trig_plot, 'cyan', 'EdgeColor', 'none', 'FaceAlpha', 0.8)
                    hold(app.UIAxes_triggered_single, 'on')
                    plot(app.UIAxes_triggered_single, app.trig_plot{app.tn}(:, 1), app.trig_plot{app.tn}(:, 2), 'k')
                    plot(app.UIAxes_triggered_single, app.trig_plot{app.tn}(:, 1), app.trig_plot{app.tn}(:, 3), 'm')
                    if ~isempty(app.peak_trig_plot{app.tn, 1})
                        plot(app.UIAxes_triggered_single, app.peak_trig_plot{app.tn, 1}(:, 1), app.peak_trig_plot{app.tn, 1}(:, 2), 'yo', 'linewidth', 2) % plot peaks on lowpassed data in single triggerd response Axes
                        plot(app.UIAxes_triggered_single, app.peak_trig_plot{app.tn, 2}(:, 1), app.peak_trig_plot{app.tn, 2}(:, 2), '>b', app.peak_trig_plot{app.tn, 3}(:, 1), app.peak_trig_plot{app.tn, 3}(:, 2), '<b', 'linewidth', 2) % plot rise and decay in single plot axes
                    end
                    hold(app.UIAxes_triggered_single, 'off')
                else
                    app.PreviousStimulationButton.Enable = 0;
                    app.NextStimulationButton.Enable = 0;     
                    app.stimuli_num_all = [];
                end
                app.roi_num_all = size(roidata{app.rn, 2}, 2)-1; % total roi number in recording-app.rn
                if isempty(app.tag_info)
                    app.tag_info = cell(size(roidata, 1)); % pre-allocate. each recording has a cell for tag in tag_info
                end
                if isempty(app.tag_info{app.rn})
                    tag_table_col_size = size(app.TagName, 2); % total column number for tag storage
                    if plot_gpio == 1
                        app.tag_info{app.rn} = cell(app.roi_num_all*app.stimuli_num_all, tag_table_col_size); % pre-allocate. each recording cell has all stim from all roi
                    else
                        app.tag_info{app.rn} = cell(app.roi_num_all, tag_table_col_size);
                    end                
                end

                if isempty(app.stimuli_num_all) % if no stimulation
                    tag_info_row = app.roi_n; % use roi number as row number
                else
                    tag_info_row = (app.roi_n-1)*app.stimuli_num_all+app.tn; % row of tags for specific ROI and triggered signal
                end
                if size(app.ROIdata, 2) >= 6 && istable(app.ROIdata{app.rn, 6})% if there is tag information stored in app.ROIdata
                    tagtable_info = app.ROIdata{app.rn, 6}.Variables;
                    app.tag_info{app.rn} = tagtable_info;
                    if ~isempty(tagtable_info{tag_info_row, 1})
                        readtags(app, tagtable_info, app.rn, app.roi_n, app.tn);
                        % app.tag_info{app.rn}(tag_info_row, :) = tagtable_info(tag_info_row, :);
                        app.NoTagInfoLabel.Text = 'Tag Info Exists';
                        app.NoTagInfoLabel.FontColor = [0.00,0.45,0.74];
                    else
                        app.NoTagInfoLabel.Text = 'No Tag Info';
                        app.NoTagInfoLabel.FontColor = [1.00,0.00,1.00];
                        % if ~isempty(app.tag_info{app.rn}{tag_info_row, 1})
                        %     readtags(app, app.tag_info{app.rn}, app.roi_n, app.tn);
                        % end
                    end
                else
                    tag_table = app.tag_info{app.rn}; % give proper structure to tag table
                    tag_table = cell2table(tag_table); % convert cell to table array
                    tag_table.Properties.VariableNames = app.TagName; % add variable names
                    app.ROIdata{app.rn, 6} = tag_table;                    
                    if ~isempty(app.tag_info{app.rn}{tag_info_row, 1})
                        readtags(app, app.tag_info{app.rn}, app.rn, app.roi_n, app.tn);
                    end
                end
                if isempty(app.discard_tag)
                	app.discard_tag = cell(size(roidata,1)); % pre-allocate. Each recording has a cell for discarding ROIs
                end
                if isempty(app.discard_tag{app.rn})
                    roirawdata = app.ROIdata{app.rn, 2};
                    roi_num_all = size(roirawdata, 2)-1; % total roi number in recording-app.rn
                    roinames = roirawdata.Properties.VariableNames(2:end);
                    discard_tag_array = zeros(1, roi_num_all);
                    discard_tag_table = array2table(discard_tag_array);
                    discard_tag_table.Properties.VariableNames = roinames;
                    app.discard_tag{app.rn} = discard_tag_table;
                end
                app.discard_tag{app.rn}{1, app.roi_n} = app.DiscardthisROICheckBox.Value;
            end
        end

        % Button pushed function: PreviousStimulationButton
        function PreviousStimulationButtonPushed(app, event)
            % tag_info_row = (app.roi_n-1)*app.stimuli_num_all+app.tn; % row of tags for specific ROI and triggered signal
            % if isempty(app.tag_info{app.rn})
            %     tag_table_col_size = size(app.TagName, 2); % total column number for tag storage
            %     app.tag_info{app.rn} = cell(app.roi_num_all*app.stimuli_num_all, tag_table_col_size); % pre-allocate. each recording cell has all stim from all roi
            % end
            % [app.tag_info{app.rn}(tag_info_row, :)] = addtags(app, app.rn, app.roi_n, app.tn); % store unsaved tags to tag_info
            
            if app.tn == 1
                % PreviousROIButtonPushed(app, event)
                if app.rn == 1 && app.roi_n == 1
                    display('This is the first ROI, no previous one')
                else
                    if app.roi_n ~= 1
                        new_recording = 0;
                        app.roi_n = app.roi_n-1;
                        if strcmp('None', app.stimulation_tag) || strcmp('Other', app.stimulation_tag)
                            plot_gpio = 0;
                        else
                            plot_gpio = 1;
                        end
                    else
                        new_recording =1;
                        app.rn = app.rn - 1;
                        app.RecordingnameLabel.Text = app.ROIdata{app.rn, 1};
                        recording_rawdata = app.ROIdata{app.rn, 2};
                        [recording_rawdata, recording_time, roi_num_all] = ROI_calc_plot(recording_rawdata);
                        app.roi_n = roi_num_all;

                        if ~isempty(app.ROIdata{app.rn, 3}) % if there is a stimulation
                            stimulation_type = app.ROIdata{app.rn, 3}{1}; % read GPIO name
                            switch stimulation_type
                            case 'OG_LED'
                                app.stimulation_tag = 'LED'; 
                                app.StimulationDropDown.Value = app.StimulationDropDown.Items{2};
                                plot_gpio = 1;
                            case 'GPIO1'
                                app.stimulation_tag = 'Airpuff';
                                app.StimulationDropDown.Value = app.StimulationDropDown.Items{3};
                                plot_gpio = 1;
                            otherwise
                                app.stimulation_tag = 'Other';
                                app.StimulationDropDown.Value = app.StimulationDropDown.Items{4};
                                app.EditField_stim.Enable = 1;
                                plot_gpio = 0;
                            end
                        else
                            app.stimulation_tag = 'None';
                            app.StimulationDropDown.Value = app.StimulationDropDown.Items{1};
                            app.StimulationdurationsDropDown.Value = app.StimulationdurationsDropDown.Items{1};
                            plot_gpio = 0;
                        end
                    end
                    cla(app.UIAxes, 'reset');
                    cla(app.UIAxes_triggered, 'reset');
                    cla(app.UIAxes_triggered_single, 'reset')

                    [recording_timeinfo, roi_col_data, roi_col_data_lowpassed] = PlotRoi(app, app.ROIdata, app.rn, app.roi_n);
                    [peak_info] = PlotPeaks(app, roi_col_data_lowpassed, app.rn, app.roi_n); % peak_info 
                    roidata = app.ROIdata;
                    
                    plot(app.UIAxes, recording_timeinfo, roi_col_data, 'k'); % plot original data
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes, recording_timeinfo, roi_col_data_lowpassed, 'm');
                    plot(app.UIAxes, peak_info(:, 1), peak_info(:, 2), 'yo', 'linewidth', 2) %plot lowpassed data peak marks
                    plot(app.UIAxes, peak_info(:, 3), peak_info(:, 4), '>b', peak_info(:, 5), peak_info(:, 6), '<b', 'linewidth', 2); % plot start and end of transient, turning point
                    % hold(app.UIAxes, 'off')

                    if plot_gpio == 1
                        app.PreviousStimulationButton.Enable = 1;
                        app.NextStimulationButton.Enable = 1;
                        ylim_gpio = ylim(app.UIAxes);
                        [gpio_x, gpio_y, gpio_train_start_time, gpio_train_end_time] = extract_gpio_into(app, app.ROIdata, app.rn);
                        gpio_y(find(gpio_y)) = ylim_gpio(2); % change gpio non-zero signal to max ylim for plot
                        gpio_y(find(gpio_y==0)) = ylim_gpio(1); % change gpio==0 to min ylim for plot
                        stimuli_method = app.StimulationDropDown.Value;
                        stimuli_duration = app.StimulationdurationsDropDown.Value;
                        app.UIAxes_triggered.Title.String = stimuli_method;
                        patch(app.UIAxes, gpio_x(:, 1), gpio_y(:, 1), 'cyan', 'EdgeColor', 'none', 'FaceAlpha', 0.8);
                        hold(app.UIAxes, 'off')

                        first_gpio_train_start_loc = find(gpio_x==gpio_train_start_time(1), 1); % location of first train starts
                        first_gpio_train_end_loc = find(gpio_x==gpio_train_end_time(1), 1, 'last'); % location of first train ends
                        app.gpio_x_trig_plot = gpio_x(first_gpio_train_start_loc:first_gpio_train_end_loc, 1); % gpio_x of the first train
                        app.gpio_x_trig_plot = app.gpio_x_trig_plot-app.gpio_x_trig_plot(1, 1); % gpio_x starts from 0
                        app.gpio_y_trig_plot = gpio_y(first_gpio_train_start_loc:first_gpio_train_end_loc); % gpio_y of the first train

                        [app.trig_plot, app.peak_trig_plot] = PlotTriggerResponse(app, app.rn, app.roi_n, gpio_train_start_time, gpio_train_end_time, peak_info); 

                        lim_gpio_trig = axis(app.UIAxes_triggered);
                        ylim_gpio = unique(app.gpio_y_trig_plot, 'sorted');
                        app.gpio_y_trig_plot(find(app.gpio_y_trig_plot==ylim_gpio(1))) = lim_gpio_trig(3); % change gpio low to min ylim for plot
                        app.gpio_y_trig_plot(find(app.gpio_y_trig_plot==ylim_gpio(2))) = lim_gpio_trig(4); % change gpio high to max ylim for plot
                        patch(app.UIAxes_triggered, app.gpio_x_trig_plot, app.gpio_y_trig_plot, 'cyan', 'EdgeColor', 'none', 'FaceAlpha', 0.8)
                        app.stimuli_num_all = size(app.peak_trig_plot, 1);
                        app.tn = app.stimuli_num_all;
                        app.UIAxes_triggered_single.Title.String = ['Stimulation ', num2str(app.tn), '/', num2str(app.stimuli_num_all)];
                        patch(app.UIAxes_triggered_single, app.gpio_x_trig_plot, app.gpio_y_trig_plot, 'cyan', 'EdgeColor', 'none', 'FaceAlpha', 0.8)
                        hold(app.UIAxes_triggered_single, 'on')
                        plot(app.UIAxes_triggered_single, app.trig_plot{app.tn}(:, 1), app.trig_plot{app.tn}(:, 2), 'k')
                        plot(app.UIAxes_triggered_single, app.trig_plot{app.tn}(:, 1), app.trig_plot{app.tn}(:, 3), 'm')
                        if ~isempty(app.peak_trig_plot{app.tn, 1})
                            plot(app.UIAxes_triggered_single, app.peak_trig_plot{app.tn, 1}(:, 1), app.peak_trig_plot{app.tn, 1}(:, 2), 'yo', 'linewidth', 2) % plot peaks on lowpassed data in single triggerd response Axes
                            plot(app.UIAxes_triggered_single, app.peak_trig_plot{app.tn, 2}(:, 1), app.peak_trig_plot{app.tn, 2}(:, 2), '>b', app.peak_trig_plot{app.tn, 3}(:, 1), app.peak_trig_plot{app.tn, 3}(:, 2), '<b', 'linewidth', 2) % plot rise and decay in single plot axes
                        end
                        hold(app.UIAxes_triggered_single, 'off')
                    else
                        app.PreviousStimulationButton.Enable = 0;
                        app.NextStimulationButton.Enable = 0;
                        app.stimuli_num_all = [];
                    end
                    app.roi_num_all = size(roidata{app.rn, 2}, 2)-1; % total roi number in recording-app.rn
                    if isempty(app.tag_info)
                        app.tag_info = cell(size(roidata, 1)); % pre-allocate. each recording has a cell for tag in tag_info
                    end
                    if isempty(app.tag_info{app.rn})
                        tag_table_col_size = size(app.TagName, 2); % total column number for tag storage
                        if plot_gpio == 1
                            app.tag_info{app.rn} = cell(app.roi_num_all*app.stimuli_num_all, tag_table_col_size); % pre-allocate. each recording cell has all stim from all roi
                        else
                            app.tag_info{app.rn} = cell(app.roi_num_all, tag_table_col_size);
                        end                    
                    end
                    if isempty(app.discard_tag)
                    	app.discard_tag = cell(size(roidata,1)); % pre-allocate. Each recording has a cell for discarding ROIs
                    end
                    if isempty(app.discard_tag{app.rn})
                    	roirawdata = app.ROIdata{app.rn, 2};
                    	roi_num_all = size(roirawdata, 2)-1; % total roi number in recording-app.rn
                    	roinames = roirawdata.Properties.VariableNames(2:end);
                    	discard_tag_array = zeros(1, roi_num_all);
                    	discard_tag_table = array2table(discard_tag_array);
                    	discard_tag_table.Properties.VariableNames = roinames;
                    	app.discard_tag{app.rn} = discard_tag_table;
                    end
                    app.discard_tag{app.rn}{1, app.roi_n} = app.DiscardthisROICheckBox.Value;
                end
            else
                cla(app.UIAxes_triggered_single, 'reset')
                app.tn = app.tn-1;
                app.UIAxes_triggered_single.Title.String = ['Stimulation ', num2str(app.tn), '/', num2str(app.stimuli_num_all)];
                patch(app.UIAxes_triggered_single, app.gpio_x_trig_plot, app.gpio_y_trig_plot, 'cyan', 'EdgeColor', 'none', 'FaceAlpha', 0.8)
                hold(app.UIAxes_triggered_single, 'on')
                plot(app.UIAxes_triggered_single, app.trig_plot{app.tn}(:, 1), app.trig_plot{app.tn}(:, 2), 'k')
                plot(app.UIAxes_triggered_single, app.trig_plot{app.tn}(:, 1), app.trig_plot{app.tn}(:, 3), 'm')
                if ~isempty(app.peak_trig_plot{app.tn, 1})
                    plot(app.UIAxes_triggered_single, app.peak_trig_plot{app.tn, 1}(:, 1), app.peak_trig_plot{app.tn, 1}(:, 2), 'yo', 'linewidth', 2) % plot peaks on lowpassed data in single triggerd response Axes
                    plot(app.UIAxes_triggered_single, app.peak_trig_plot{app.tn, 2}(:, 1), app.peak_trig_plot{app.tn, 2}(:, 2), '>b', app.peak_trig_plot{app.tn, 3}(:, 1), app.peak_trig_plot{app.tn, 3}(:, 2), '<b', 'linewidth', 2) % plot rise and decay in single plot axes
                end
                hold(app.UIAxes_triggered_single, 'off')
            end
            if isempty(app.stimuli_num_all) % if no stimulation
                tag_info_row = app.roi_n; % use roi number as row number
            else
                tag_info_row = (app.roi_n-1)*app.stimuli_num_all+app.tn; % row of tags for specific ROI and triggered signal
            end
            if size(app.ROIdata, 2) >= 6 && istable(app.ROIdata{app.rn, 6})% if there is tag information stored in app.ROIdata
                tagtable_info = app.ROIdata{app.rn, 6}.Variables;
                app.tag_info{app.rn} = tagtable_info;
                if ~isempty(tagtable_info{tag_info_row, 1})
                    readtags(app, tagtable_info, app.rn, app.roi_n, app.tn);
                    % app.tag_info{app.rn}(tag_info_row, :) = tagtable_info(tag_info_row, :);
                    app.NoTagInfoLabel.Text = 'Tag Info Exists';
                    app.NoTagInfoLabel.FontColor = [0.00,0.45,0.74];
                else
                    app.NoTagInfoLabel.Text = 'No Tag Info';
                    app.NoTagInfoLabel.FontColor = [1.00,0.00,1.00];
                    % if ~isempty(app.tag_info{app.rn}{tag_info_row, 1})
                    %     readtags(app, app.tag_info{app.rn}, app.roi_n, app.tn);
                    % end
                end
            else
                if ~isempty(app.tag_info{app.rn}{tag_info_row, 1})
                    readtags(app, app.tag_info{app.rn}, app.rn, app.roi_n, app.tn);
                end
            end
        end

        % Button pushed function: NextStimulationButton
        function NextStimulationButtonPushed(app, event)
            % tag_info_row = (app.roi_n-1)*app.stimuli_num_all+app.tn; % row of tags for specific ROI and triggered signal
            % if isempty(app.tag_info{app.rn})
            %     tag_table_col_size = size(app.TagName, 2); % total column number for tag storage
            %     app.tag_info{app.rn} = cell(app.roi_num_all*app.stimuli_num_all, tag_table_col_size); % pre-allocate. each recording cell has all stim from all roi
            % end
            % [app.tag_info{app.rn}(tag_info_row, :)] = addtags(app, app.rn, app.roi_n, app.tn); % store unsaved tags to tag_info
            
            if app.tn == app.stimuli_num_all
                NextROIButtonPushed(app, event)
            else
                cla(app.UIAxes_triggered_single, 'reset')
                app.tn = app.tn+1;
                app.UIAxes_triggered_single.Title.String = ['Stimulation ', num2str(app.tn), '/', num2str(app.stimuli_num_all)];
                patch(app.UIAxes_triggered_single, app.gpio_x_trig_plot, app.gpio_y_trig_plot, 'cyan', 'EdgeColor', 'none', 'FaceAlpha', 0.8)
                hold(app.UIAxes_triggered_single, 'on')
                plot(app.UIAxes_triggered_single, app.trig_plot{app.tn}(:, 1), app.trig_plot{app.tn}(:, 2), 'k')
                plot(app.UIAxes_triggered_single, app.trig_plot{app.tn}(:, 1), app.trig_plot{app.tn}(:, 3), 'm')
                if ~isempty(app.peak_trig_plot{app.tn, 1})
                    plot(app.UIAxes_triggered_single, app.peak_trig_plot{app.tn, 1}(:, 1), app.peak_trig_plot{app.tn, 1}(:, 2), 'yo', 'linewidth', 2) % plot peaks on lowpassed data in single triggerd response Axes
                    plot(app.UIAxes_triggered_single, app.peak_trig_plot{app.tn, 2}(:, 1), app.peak_trig_plot{app.tn, 2}(:, 2), '>b', app.peak_trig_plot{app.tn, 3}(:, 1), app.peak_trig_plot{app.tn, 3}(:, 2), '<b', 'linewidth', 2) % plot rise and decay in single plot axes
                end
                hold(app.UIAxes_triggered_single, 'off')
            end
            if isempty(app.stimuli_num_all) % if no stimulation
                tag_info_row = app.roi_n; % use roi number as row number
            else
                tag_info_row = (app.roi_n-1)*app.stimuli_num_all+app.tn; % row of tags for specific ROI and triggered signal
            end
            if size(app.ROIdata, 2) >= 6 && istable(app.ROIdata{app.rn, 6})% if there is tag information stored in app.ROIdata
                tagtable_info = app.ROIdata{app.rn, 6}.Variables;
                app.tag_info{app.rn} = tagtable_info;
                if ~isempty(tagtable_info{tag_info_row, 1})
                    readtags(app, tagtable_info, app.rn, app.roi_n, app.tn);
                    % app.tag_info{app.rn}(tag_info_row, :) = tagtable_info(tag_info_row, :);
                    app.NoTagInfoLabel.Text = 'Tag Info Exists';
                    app.NoTagInfoLabel.FontColor = [0.00,0.45,0.74];
                else
                    app.NoTagInfoLabel.Text = 'No Tag Info';
                    app.NoTagInfoLabel.FontColor = [1.00,0.00,1.00];
                    % if ~isempty(app.tag_info{app.rn}{tag_info_row, 1})
                    %     readtags(app, app.tag_info{app.rn}, app.roi_n, app.tn);
                    % end
                end
            else
                if ~isempty(app.tag_info{app.rn}{tag_info_row, 1})
                    readtags(app, app.tag_info{app.rn}, app.rn, app.roi_n, app.tn);
                end
            end
            if isempty(app.discard_tag)
            	app.discard_tag = cell(size(roidata,1)); % pre-allocate. Each recording has a cell for discarding ROIs
            end
            if isempty(app.discard_tag{app.rn})
                roirawdata = app.ROIdata{app.rn, 2};
                roi_num_all = size(roirawdata, 2)-1; % total roi number in recording-app.rn
                roinames = roirawdata.Properties.VariableNames(2:end);
                discard_tag_array = zeros(1, roi_num_all);
                discard_tag_table = array2table(discard_tag_array);
                discard_tag_table.Properties.VariableNames = roinames;
                app.discard_tag{app.rn} = discard_tag_table;
            end
            app.discard_tag{app.rn}{1, app.roi_n} = app.DiscardthisROICheckBox.Value;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            
        end

        % Button pushed function: SaveTagsButton
        function SaveTagsButtonPushed(app, event)
            if isempty(app.stimuli_num_all) % if no stimulation
                tag_info_row = app.roi_n; % use roi number as row number
            else
                tag_info_row = (app.roi_n-1)*app.stimuli_num_all+app.tn; % row of tags for specific ROI and triggered signal
            end
            [app.tag_info{app.rn}(tag_info_row, :), app.discard_tag{app.rn}.Variables] = addtags(app, app.rn, app.roi_n, app.tn); % store unsaved tags to tag_info
            app.ROIdata{app.rn, 6}(tag_info_row, :).Variables = app.tag_info{app.rn}(tag_info_row, :);
            
            
            app.ROIdata{app.rn, 7} = app.discard_tag{app.rn};
            
            app.NoTagInfoLabel.Text = 'Tag Info Exists';
            app.NoTagInfoLabel.FontColor = [0.00,0.45,0.74];            
        end

        % Button pushed function: ExportMatfileButton
        function ExportMatfileButtonPushed(app, event)
            [ROIdata_file, ROIdata_path] = uiputfile([app.folder_ROIdata, '/*.mat'], 'Save ROIdata into a .matfile');
            if isequal(ROIdata_file,0) || isequal(ROIdata_path,0)
               disp('User clicked Cancel.')
            else
               disp(['User selected ',fullfile(ROIdata_path,ROIdata_file),...
                     ' and then clicked Save.'])
                     roidata_tag = app.ROIdata;
               save(fullfile(ROIdata_path,ROIdata_file),'roidata_tag');
            end
        end

        % Value changed function: DOEDatePicker
        function DOEDatePickerValueChanged(app, event)
            value = app.DOEDatePicker.Value;
            DOB = app.DOBDatePicker.Value; % date of birth
            DOI = app.DOIDatePicker.Value; % date of virus injection
            DOE = app.DOEDatePicker.Value; % date of experiment
            if isnat(DOE) 
                transfection_day = 0;
                mouseAge = 0;
            else
                if isnat(DOI)
                    transfection_day = 0;
                else
                    transfection_day = caldiff([DOI; DOE], 'days'); % days of transfection
                    transfection_day = split(transfection_day, 'days'); % transfection time (from injection to experiment)
                end
                if isnat(DOB)
                    mouseAge = 0;
                else
                    mouseAge = caldiff([DOB; DOE], 'days'); % age in days
                    mouseAge = split(mouseAge, 'days'); % mouse age in days when it's sacrificed
                end
            end
            app.EditField_transfection_day.Value = transfection_day;
            app.EditField_age.Value = mouseAge;            
        end

        % Value changed function: DOBDatePicker
        function DOBDatePickerValueChanged(app, event)
            value = app.DOBDatePicker.Value;
            DOB = app.DOBDatePicker.Value; % date of birth
            DOI = app.DOIDatePicker.Value; % date of virus injection
            DOE = app.DOEDatePicker.Value; % date of experiment
            if isnat(DOE) 
                transfection_day = 0;
                mouseAge = 0;
            else
                if isnat(DOI)
                    transfection_day = 0;
                else
                    transfection_day = caldiff([DOI; DOE], 'days'); % days of transfection
                    transfection_day = split(transfection_day, 'days'); % transfection time (from injection to experiment)
                end
                if isnat(DOB)
                    mouseAge = 0;
                else
                    mouseAge = caldiff([DOB; DOE], 'days'); % age in days
                    mouseAge = split(mouseAge, 'days'); % mouse age in days when it's sacrificed
                end
            end
            app.EditField_transfection_day.Value = transfection_day;
            app.EditField_age.Value = mouseAge;            
        end

        % Value changed function: DOIDatePicker
        function DOIDatePickerValueChanged(app, event)
            value = app.DOIDatePicker.Value;
            DOB = app.DOBDatePicker.Value; % date of birth
            DOI = app.DOIDatePicker.Value; % date of virus injection
            DOE = app.DOEDatePicker.Value; % date of experiment
            if isnat(DOE) 
                transfection_day = 0;
                mouseAge = 0;
            else
                if isnat(DOI)
                    transfection_day = 0;
                else
                    transfection_day = caldiff([DOI; DOE], 'days'); % days of transfection
                    transfection_day = split(transfection_day, 'days'); % transfection time (from injection to experiment)
                end
                if isnat(DOB)
                    mouseAge = 0;
                else
                    mouseAge = caldiff([DOB; DOE], 'days'); % age in days
                    mouseAge = split(mouseAge, 'days'); % mouse age in days when it's sacrificed
                end
            end
            app.EditField_transfection_day.Value = transfection_day;
            app.EditField_age.Value = mouseAge;            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            modifier = event.Modifier;
            % disp(key)
            if strcmp(modifier, 'control')
                if strcmp(key, 's')
                    SaveTagsButtonPushed(app, event)
                elseif strcmp(key, 'rightarrow')
                    NextROIButtonPushed(app, event)
                elseif strcmp(key, 'leftarrow')
                    PreviousROIButtonPushed(app, event)
                elseif strcmp(key, 'p')
                    app.ROIdata{app.rn, 6}
                end
            elseif isempty(modifier)
                if app.stimuli_num_all ~= 0
                    if strcmp(key, 'rightarrow')
                        NextStimulationButtonPushed(app, event)
                    elseif strcmp(key, 'leftarrow')
                        PreviousStimulationButtonPushed(app, event)
                    end
  	        end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.CheckROIsandTagRecordingsLabel = uilabel(app.UIFigure);
            app.CheckROIsandTagRecordingsLabel.Text = 'Check ROIs and Tag Recordings';
            app.DataPanel = uipanel(app.UIFigure);
            app.DataPanel.Title = 'Data';
            app.ROIdatafileEditFieldLabel = uilabel(app.DataPanel);
            app.ROIdatafileEditFieldLabel.Text = 'ROI data file';
            app.ROIdatafileEditField = uieditfield(app.DataPanel, 'text');
            app.ViarablenameLabel = uilabel(app.DataPanel);
            app.ViarablenameLabel.Text = 'Viarable name:';
            app.viarableloadedLabel_name = uilabel(app.DataPanel);
            app.viarableloadedLabel_name.Text = 'no viarable loaded';
            app.BrowseButton_ROIdata = uibutton(app.DataPanel, 'push');
            app.BrowseButton_ROIdata.Text = 'Browse';
            app.DataPanel_2 = uipanel(app.UIFigure);
            app.DataPanel_2.Title = 'Data';
            app.ROIdatafileEditField_2Label = uilabel(app.DataPanel_2);
            app.ROIdatafileEditField_2Label.Text = 'ROI data file';
            app.ROIdatafileEditField_2 = uieditfield(app.DataPanel_2, 'text');
            app.ViarablenameLabel_2 = uilabel(app.DataPanel_2);
            app.ViarablenameLabel_2.Text = 'Viarable name:';
            app.viarableloadedLabel_name_2 = uilabel(app.DataPanel_2);
            app.viarableloadedLabel_name_2.Text = 'no viarable loaded';
            app.BrowseButton_ROIdata_2 = uibutton(app.DataPanel_2, 'push');
            app.BrowseButton_ROIdata_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_ROIdata_2Pushed, true);
            app.BrowseButton_ROIdata_2.Text = 'Browse';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            title(app.UIAxes, 'ROI name')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'DF/F')
            app.UIAxes_triggered = uiaxes(app.PlotTab);
            title(app.UIAxes_triggered, 'Tiggered response overlap')
            xlabel(app.UIAxes_triggered, 'Time (s)')
            ylabel(app.UIAxes_triggered, '')
            app.PreviousROIButton = uibutton(app.PlotTab, 'push');
            app.PreviousROIButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousROIButtonPushed, true);
            app.PreviousROIButton.Tooltip = {'Ctrl+left'};
            app.PreviousROIButton.Text = 'Previous ROI';
            app.NextROIButton = uibutton(app.PlotTab, 'push');
            app.NextROIButton.ButtonPushedFcn = createCallbackFcn(app, @NextROIButtonPushed, true);
            app.NextROIButton.Tooltip = {'Ctrl+right'};
            app.NextROIButton.Text = 'Next ROI';
            app.RecordingnameLabel = uilabel(app.PlotTab);
            app.RecordingnameLabel.Text = 'Recording name';
            app.PlotButton = uibutton(app.PlotTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.UIAxes_triggered_single = uiaxes(app.PlotTab);
            title(app.UIAxes_triggered_single, 'Tiggered response single')
            xlabel(app.UIAxes_triggered_single, 'Time (s)')
            ylabel(app.UIAxes_triggered_single, '')
            app.PreviousStimulationButton = uibutton(app.PlotTab, 'push');
            app.PreviousStimulationButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousStimulationButtonPushed, true);
            app.PreviousStimulationButton.Tooltip = {'left'};
            app.PreviousStimulationButton.Text = 'Previous Stimulation';
            app.NextStimulationButton = uibutton(app.PlotTab, 'push');
            app.NextStimulationButton.ButtonPushedFcn = createCallbackFcn(app, @NextStimulationButtonPushed, true);
            app.NextStimulationButton.Tooltip = {'right '};
            app.NextStimulationButton.Text = 'Next Stimulation';
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Table';
            app.UITable = uitable(app.Table);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.TagsPanel = uipanel(app.UIFigure);
            app.TagsPanel.Title = 'Tags';
            app.StimulationDropDownLabel = uilabel(app.TagsPanel);
            app.StimulationDropDownLabel.Text = 'Stimulation';
            app.StimulationDropDown = uidropdown(app.TagsPanel);
            app.StimulationDropDown.Items = {'None', 'LED', 'Airpuff', 'Other'};
            app.StimulationDropDown.Value = 'LED';
            app.EditField_stim = uieditfield(app.TagsPanel, 'text');
            app.EditField_stim.Enable = 'off';
            app.StimulationdurationsLabel = uilabel(app.TagsPanel);
            app.StimulationdurationsLabel.Text = 'Stimulation duration (s)';
            app.StimulationdurationsDropDown = uidropdown(app.TagsPanel);
            app.StimulationdurationsDropDown.Items = {'None', '1', '5', '10', 'Custom'};
            app.StimulationdurationsDropDown.Value = '1';
            app.StableBaselineDropDownLabel = uilabel(app.TagsPanel);
            app.StableBaselineDropDownLabel.Text = 'Stable Baseline';
            app.StableBaselineDropDown = uidropdown(app.TagsPanel);
            app.StableBaselineDropDown.Items = {'Yes', 'No', 'Up', 'Down'};
            app.StableBaselineDropDown.Value = 'Yes';
            app.CalciumIndicatorDropDownLabel = uilabel(app.TagsPanel);
            app.CalciumIndicatorDropDownLabel.Text = 'Calcium Indicator';
            app.CalciumIndicatorDropDown = uidropdown(app.TagsPanel);
            app.CalciumIndicatorDropDown.Items = {'GCaMP6s', 'GCaMP6f', 'GCaMP6f (straight)', 'Other'};
            app.CalciumIndicatorDropDown.Value = 'GCaMP6s';
            app.EditField_CaIn = uieditfield(app.TagsPanel, 'text');
            app.EditField_CaIn.Enable = 'off';
            app.EditField_stim_duration = uieditfield(app.TagsPanel, 'numeric');
            app.EditField_stim_duration.Enable = 'off';
            app.StimulationEffectsSingleTrialPanel = uipanel(app.TagsPanel);
            app.StimulationEffectsSingleTrialPanel.Title = 'Stimulation Effect(s) (Single Trial)';
            app.InduceCalciumtransientCheckBox = uicheckbox(app.StimulationEffectsSingleTrialPanel);
            app.InduceCalciumtransientCheckBox.Text = 'Induce Calcium transient';
            app.SupressCalciumtransientCheckBox = uicheckbox(app.StimulationEffectsSingleTrialPanel);
            app.SupressCalciumtransientCheckBox.Text = 'Supress Calcium transient';
            app.ReboundCalciumtransientafterstimulationCheckBox = uicheckbox(app.StimulationEffectsSingleTrialPanel);
            app.ReboundCalciumtransientafterstimulationCheckBox.Text = 'Rebound Calcium transient (after stimulation)';
            app.RecordingFrequencyHzDropDownLabel = uilabel(app.TagsPanel);
            app.RecordingFrequencyHzDropDownLabel.Text = {'Recording Frequency '; '(Hz)'};
            app.RecordingFrequencyHzDropDown = uidropdown(app.TagsPanel);
            app.RecordingFrequencyHzDropDown.Items = {'10', '20', '30', 'Custom'};
            app.RecordingFrequencyHzDropDown.Value = '10';
            app.EditField_record_fq = uieditfield(app.TagsPanel, 'numeric');
            app.EditField_record_fq.Enable = 'off';
            app.PartofneuronDropDownLabel = uilabel(app.TagsPanel);
            app.PartofneuronDropDownLabel.Text = 'Part of neuron';
            app.PartofneuronDropDown = uidropdown(app.TagsPanel);
            app.PartofneuronDropDown.Items = {'Soma', 'Process', 'Other'};
            app.PartofneuronDropDown.Value = 'Soma';
            app.EditField_neuron_part = uieditfield(app.TagsPanel, 'text');
            app.EditField_neuron_part.Enable = 'off';
            app.MouseIDEditFieldLabel = uilabel(app.TagsPanel);
            app.MouseIDEditFieldLabel.Text = 'Mouse ID';
            app.MouseIDEditField = uieditfield(app.TagsPanel, 'text');
            app.DOBDatePickerLabel = uilabel(app.TagsPanel);
            app.DOBDatePickerLabel.Text = 'DOB';
            app.DOBDatePicker = uidatepicker(app.TagsPanel);
            app.DOBDatePicker.ValueChangedFcn = createCallbackFcn(app, @DOBDatePickerValueChanged, true);
            app.DOBDatePicker.Tooltip = {'Date of Birth'};
            app.DOBDatePicker.Value = datetime([2019 11 27]);
            app.DOEDatePickerLabel = uilabel(app.TagsPanel);
            app.DOEDatePickerLabel.Text = 'DOE';
            app.DOEDatePicker = uidatepicker(app.TagsPanel);
            app.DOEDatePicker.ValueChangedFcn = createCallbackFcn(app, @DOEDatePickerValueChanged, true);
            app.DOEDatePicker.Tooltip = {'Date of Experiment'};
            app.WeightgEditFieldLabel = uilabel(app.TagsPanel);
            app.WeightgEditFieldLabel.Text = 'Weight (g)';
            app.WeightgEditField = uieditfield(app.TagsPanel, 'numeric');
            app.OptoactuatorDropDownLabel = uilabel(app.TagsPanel);
            app.OptoactuatorDropDownLabel.Text = 'Opto actuator';
            app.OptoactuatorDropDown = uidropdown(app.TagsPanel);
            app.OptoactuatorDropDown.Items = {'ChrimsonR', 'Channelrhodopsin', 'None', 'Other'};
            app.OptoactuatorDropDown.Value = 'ChrimsonR';
            app.EditField_opto_actuator = uieditfield(app.TagsPanel, 'text');
            app.EditField_opto_actuator.Enable = 'off';
            app.SaveTagsButton = uibutton(app.TagsPanel, 'push');
            app.SaveTagsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTagsButtonPushed, true);
            app.SaveTagsButton.Tooltip = {'Ctrl+s'};
            app.SaveTagsButton.Text = 'Save Tags';
            app.DOIDatePickerLabel = uilabel(app.TagsPanel);
            app.DOIDatePickerLabel.Text = 'DOI';
            app.DOIDatePicker = uidatepicker(app.TagsPanel);
            app.DOIDatePicker.ValueChangedFcn = createCallbackFcn(app, @DOIDatePickerValueChanged, true);
            app.DOIDatePicker.Tooltip = {'Date of Virus Injeciton'};
            app.EditField_transfection_day = uieditfield(app.TagsPanel, 'numeric');
            app.EditField_transfection_day.Tooltip = {'Transfection time'};
            app.EditField_age = uieditfield(app.TagsPanel, 'numeric');
            app.EditField_age.Tooltip = {'Age'};
            app.SexDropDownLabel = uilabel(app.TagsPanel);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.TagsPanel);
            app.SexDropDown.Items = {'Male', 'Female'};
            app.SexDropDown.Value = 'Male';
            app.AnesthesiaPanel = uipanel(app.TagsPanel);
            app.AnesthesiaPanel.Title = 'Anesthesia ';
            app.IsofluraneCheckBox = uicheckbox(app.AnesthesiaPanel);
            app.IsofluraneCheckBox.Text = 'Isoflurane';
            app.ChloraloseCheckBox = uicheckbox(app.AnesthesiaPanel);
            app.ChloraloseCheckBox.Text = 'Chloralose';
            app.ConcentrationDropDown = uidropdown(app.AnesthesiaPanel);
            app.ConcentrationDropDown.Items = {'Concentration', 'Unknown'};
            app.ConcentrationDropDown.Value = 'Concentration';
            app.EditField_IsoCon = uieditfield(app.AnesthesiaPanel, 'numeric');
            app.Label = uilabel(app.AnesthesiaPanel);
            app.Label.Text = '%';
            app.NotesTextAreaLabel = uilabel(app.TagsPanel);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.TagsPanel);
            app.daysLabel = uilabel(app.TagsPanel);
            app.daysLabel.Text = 'days';
            app.daysLabel_2 = uilabel(app.TagsPanel);
            app.daysLabel_2.Text = 'days';
            app.ExportMatfileButton = uibutton(app.TagsPanel, 'push');
            app.ExportMatfileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMatfileButtonPushed, true);
            app.ExportMatfileButton.Text = 'Export Mat file';
            app.NoTagInfoLabel = uilabel(app.TagsPanel);
            app.NoTagInfoLabel.Text = 'No Tag Info';
            app.DiscardthisROICheckBox = uicheckbox(app.TagsPanel);
            app.DiscardthisROICheckBox.Text = 'Discard this ROI';
            app.SameFOVexistsCheckBox = uicheckbox(app.TagsPanel);
            app.SameFOVexistsCheckBox.Text = 'Same FOV exists';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nVoke_ROI_tag

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end