classdef nvoke_export_file < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ExportInscopixRecordingFilesisxdLabel  matlab.ui.control.Label
        ExportPanel                  matlab.ui.container.Panel
        CheckOnlyWithROI             matlab.ui.control.CheckBox
        CheckVideoMotionCorrected    matlab.ui.control.CheckBox
        CheckVideoDFF                matlab.ui.control.CheckBox
        CheckROIInfo                 matlab.ui.control.CheckBox
        CheckGPIOInfo                matlab.ui.control.CheckBox
        EditFolderPath_output        matlab.ui.control.EditField
        BrowseFolder_output          matlab.ui.control.Button
        OutputfolderLabel            matlab.ui.control.Label
        ExportButton                 matlab.ui.control.Button
        SourceFilesButtonGroup       matlab.ui.container.ButtonGroup
        ExportSingleRecordingButton  matlab.ui.control.RadioButton
        ExportAllRecordingsinafolderButton  matlab.ui.control.RadioButton
        EditFilePath                 matlab.ui.control.EditField
        BrowseFile                   matlab.ui.control.Button
        EditFolderPath               matlab.ui.control.EditField
        BrowseFolder                 matlab.ui.control.Button
        appcondition                 matlab.ui.control.Label
        exporting_count              matlab.ui.control.Label
    end

    
    properties (Access = public)
        folder_input = 'G:\Workspace\Inscopix_Seagate\Projects'; % default folder location for all recordings
        folder_output = 'G:\Workspace\Inscopix_Seagate\Analysis'; % default folder location for all recordings
    end
    
    methods
        function ExportFiles(app, RecordingFilepath) % RecordingFilepath is a full path of recording file ".....-PP-BP-MC.isxd". Use k = strfind(str, '-PP') to find the location of "-PP.isxd"
            [app.folder_input,File_input,ext] = fileparts(RecordingFilepath); 
        % export motion corrected video    
        if app.CheckVideoMotionCorrected.Value == 1
            input_movie_file_mc = fullfile(app.folder_input, [File_input, '.isxd']);
            output_tiff_file_mc = fullfile(app.EditFolderPath_output.Value, [File_input, '.tiff']);
            if ~exist(input_movie_file_mc, 'file')
                disp(['Input motion corrected movie file ', input_movie_file_mc, 'not found'])
            else
                if ~exist(output_tiff_file_mc, 'file')
                    isx.export_movie_to_tiff(input_movie_file_mc, output_tiff_file_mc);
                    disp([' - Exported:', File_input, '.tiff'])
                end
            end
        end
        % export DFF video
        if app.CheckVideoDFF.Value == 1
            input_movie_file_dff = fullfile(app.folder_input, [File_input, '-DFF.isxd']);
            output_tiff_file_dff = fullfile(app.EditFolderPath_output.Value, [File_input, '-DFF.tiff']);
            if ~exist(input_movie_file_dff, 'file')
                disp(['Input DFF movie file ', input_movie_file_dff, 'not found'])
            else
                if ~exist(output_tiff_file_dff, 'file')
                    isx.export_movie_to_tiff(input_movie_file_dff, output_tiff_file_dff);
                    disp([' - Exported:', File_input, '-DFF.tiff'])
                end
            end
        end
        % export GPIO file
        % API doesn't support it yet
        
        % export ROI
        if app.CheckROIInfo.Value == 1
            input_movie_file_ROI = fullfile(app.folder_input, [File_input, '-DFF-ROI.isxd']);
            output_csv_file_ROI = fullfile(folder.EditFolderPath_output.Value,[File_input, '-DFF-ROI.csv']);
            output_tiff_file_dff = fullfile(folder.EditFolderPath_output.Value, [File_input, '-DFF-ROI.tiff']);
            if ~exist(input_movie_file_ROI, 'file')
                disp(['Input motion corrected movie file ', input_movie_file_ROI, 'not found'])
            else
                if ~exist(output_csv_file_ROI, 'file')
                    % isx.export_cell_set_to_csv_tiff(input_movie_file_ROI, output_csv_file_ROI);
                    isx.export_cell_set_to_csv_tiff(input_movie_file_ROI, output_csv_file_ROI, output_tiff_file_dff);
                    disp([' - Exported:', File_input, '-DFF-ROI.csv', ', ', File_input, '-DFF-ROI.tiff'])
                end
            end
        end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseFile
        function BrowseFilePushed(app, event)
           [RecordingFile, RecordingPath] = uigetfile([app.folder_input, '\*-PP-BP-MC.isxd'], 'Choose a processed file (motion corrected)'); % input file path info. Choose a motion corrected file
           RecordingFilepath = fullfile(RecordingPath, RecordingFile);
            if RecordingFile ~= 0 
                app.EditFilePath.Value = RecordingFilepath;
                app.folder_input = RecordingPath;
            end 
        end

        % Button pushed function: BrowseFolder
        function BrowseFolderPushed(app, event)
            RecordingPath = uigetdir([app.folder_input, '\'], 'Choose a folder including processed recordings'); % input folder
            if RecordingPath ~= 0
                app.EditFolderPath.Value = RecordingPath;
                app.folder_input = RecordingPath;
            end
                
        end

        % Button pushed function: ExportButton
        function ExportPushed(app, event)
            switch app.SourceFilesButtonGroup.SelectedObject.Text
                case 'Export Single Recording' 
                    [app.folder_input,file_mc,ext] = fileparts(app.EditFilePath.Value);
                    if app.CheckOnlyWithROI.Value == 1
                        file_DFF = fullfile(app.folder_input, [file_mc, '-DFF.isxd']);
                        file_roi = fullfile(app.folder_input, [file_mc, '-DFF-ROI.isxd']);
                        if exist(file_roi, 'file')
                            disp(['Working on recording 1/1: ', file_mc(1:(end-9))]);
                            % app.appcondition.Text = ['Exporting recording: ', file_mc(1:(end-9))];
                            ExportFiles(app,app.EditFilePath.Value);
                        else
                            disp(['no ROI file found for ', file_DFF]);
                        end
                    else
                        disp(['Working on recording 1/1: ', file_mc(1:(end-9))]);
                        % app.appcondition.Text = ['Exporting recording: ', file_mc(1:(end-9))];
                        ExportFiles(app, app.EditFilePath.Value);
                    end
                case 'Export All Recordings in a folder'
                    if app.CheckOnlyWithROI.Value == 1
                        export_roi_files = dir([app.EditFolderPath.Value, '\', '*-MC-DFF-ROI.isxd']);
                        roi_file_num = numel(export_roi_files); % number of files with roi info
                        for n = 1:roi_file_num
                            disp(['Recording ', n, '/', roi_file_num, ': ', export_roi_files(n).name(1:(end-22))]); % export_roi_files(n).name(1:(end-22)) is file name before '-PP'
                            % app.appcondition.Text = ['Exporting recording: ', export_roi_files(n).name(1:(end-22))];
                            % app.exporting_count.Text = [n, '/', roi_file_num];
                            RecordingFilepath = fullfile(app.EditFolderPath.Value, [export_roi_files(n).name(1:(end-13)), '.isxd']); % export_roi_files.name(1:(end-13)) is file "*-MC"
                            ExportFiles(app, RecordingFilepath);
                        end
                        app.exporting_count.Text = ' ';
                    else
                        export_recordings = dir([app.EditFolderPath.Value, '\', '*-MC.isxd']);
                        recording_num = numel(export_recordings);
                        for n = 1: recording_num
                            disp(['Working on recording ', n, '/', recording_num, ': ', export_recordings(n).name(1:(end-9))]); % export_roi_files(n).name(1:(end-9)) is file name before '-PP'
                            % app.appcondition.Text = ['Exporting recording: ', export_recordings(n).name(1:(end-9))];
                            % app.exporting_count.Text = [n, '/', recording_num];
                            RecordingFilepath = fullfile(app.EditFolderPath.Value, export_recordings(n).name); 
                            ExportFiles(app, RecordingFilepath);
                        end
                        % app.exporting_count.Text = ' ';
                    end
            end
            disp('Completed ');
            % app.appcondition.Text = 'Idle';

        end

        % Button pushed function: BrowseFolder_output
        function BrowseFolder_outputButtonPushed(app, event)
            OutputPath = uigetdir([app.folder_output, '\'], 'Choose a Folder to save exported files'); % output folder
            if OutputPath ~= 0
                app.EditFolderPath_output.Value = OutputPath;
                app.folder_output = OutputPath;
            end
        end

        % Selection changed function: SourceFilesButtonGroup
        function SourceFilesButtonGroupSelectionChanged(app, event)
            selectedButton = app.SourceFilesButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Export Single Recording'
                    app.EditFilePath.Enable = 1;
                    app.BrowseFile.Enable = 1;
                    app.EditFolderPath.Enable = 0;
                    app.BrowseFolder.Enable = 0;
                case 'Export All Recordings in a folder'
                    app.EditFilePath.Enable = 0;
                    app.BrowseFile.Enable = 0;
                    app.EditFolderPath.Enable = 1;
                    app.BrowseFolder.Enable = 1;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ExportInscopixRecordingFilesisxdLabel = uilabel(app.UIFigure);
            app.ExportInscopixRecordingFilesisxdLabel.Text = 'Export Inscopix Recording Files (.isxd) ';
            app.ExportPanel = uipanel(app.UIFigure);
            app.ExportPanel.Title = 'Export';
            app.CheckOnlyWithROI = uicheckbox(app.ExportPanel);
            app.CheckOnlyWithROI.Text = 'Only export recordings with ROI info';
            app.CheckVideoMotionCorrected = uicheckbox(app.ExportPanel);
            app.CheckVideoMotionCorrected.Text = 'Video - Motion Corrected (.tiff)';
            app.CheckVideoMotionCorrected.Value = true;
            app.CheckVideoDFF = uicheckbox(app.ExportPanel);
            app.CheckVideoDFF.Text = 'Video - DFF (.tiff)';
            app.CheckVideoDFF.Value = true;
            app.CheckROIInfo = uicheckbox(app.ExportPanel);
            app.CheckROIInfo.Text = 'ROI info (.csv & .tiff)';
            app.CheckGPIOInfo = uicheckbox(app.ExportPanel);
            app.CheckGPIOInfo.Enable = 'off';
            app.CheckGPIOInfo.Tooltip = {'Waiting for update of IDPS API to have this function'};
            app.CheckGPIOInfo.Text = 'GPIO info (.csv)';
            app.EditFolderPath_output = uieditfield(app.ExportPanel, 'text');
            app.EditFolderPath_output.Value = 'G:\Workspace\Inscopix_Seagate\Analysis';
            app.BrowseFolder_output = uibutton(app.ExportPanel, 'push');
            app.BrowseFolder_output.ButtonPushedFcn = createCallbackFcn(app, @BrowseFolder_outputButtonPushed, true);
            app.BrowseFolder_output.Tooltip = {'Choose a folder to save exported files'};
            app.BrowseFolder_output.Text = 'Browse';
            app.OutputfolderLabel = uilabel(app.ExportPanel);
            app.OutputfolderLabel.Text = 'Output folder';
            app.ExportButton = uibutton(app.ExportPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPushed, true);
            app.ExportButton.Text = 'Export';
            app.SourceFilesButtonGroup = uibuttongroup(app.UIFigure);
            app.SourceFilesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SourceFilesButtonGroupSelectionChanged, true);
            app.SourceFilesButtonGroup.Title = 'Source File(s)';
            app.ExportSingleRecordingButton = uiradiobutton(app.SourceFilesButtonGroup);
            app.ExportSingleRecordingButton.Text = 'Export Single Recording';
            app.ExportSingleRecordingButton.Value = true;
            app.ExportAllRecordingsinafolderButton = uiradiobutton(app.SourceFilesButtonGroup);
            app.ExportAllRecordingsinafolderButton.Text = 'Export All Recordings in a folder';
            app.EditFilePath = uieditfield(app.SourceFilesButtonGroup, 'text');
            app.EditFilePath.Value = 'G:\Workspace\Inscopix_Seagate\Projects';
            app.BrowseFile = uibutton(app.SourceFilesButtonGroup, 'push');
            app.BrowseFile.ButtonPushedFcn = createCallbackFcn(app, @BrowseFilePushed, true);
            app.BrowseFile.Tooltip = {'Choose a motion corrected recording (*-MC.isxd)'};
            app.BrowseFile.Text = 'Browse';
            app.EditFolderPath = uieditfield(app.SourceFilesButtonGroup, 'text');
            app.EditFolderPath.Enable = 'off';
            app.EditFolderPath.Value = 'G:\Workspace\Inscopix_Seagate\Projects';
            app.BrowseFolder = uibutton(app.SourceFilesButtonGroup, 'push');
            app.BrowseFolder.ButtonPushedFcn = createCallbackFcn(app, @BrowseFolderPushed, true);
            app.BrowseFolder.Enable = 'off';
            app.BrowseFolder.Tooltip = {'Choose a folder containing motion corrected recordings (*-MC.isxd)'};
            app.BrowseFolder.Text = 'Browse';
            app.appcondition = uilabel(app.UIFigure);
            app.appcondition.Text = '';
            app.exporting_count = uilabel(app.UIFigure);
            app.exporting_count.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nvoke_export_file

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end