classdef plot_roi_gpio_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        edit_roi_readout_file        matlab.ui.control.EditField
        edit_gpio_file               matlab.ui.control.EditField
        pushbutton_browse_roi_readout_file  matlab.ui.control.Button
        pushbutton_browse_gpio_file  matlab.ui.control.Button
        roi_readout                  matlab.ui.control.Label
        gpio_file                    matlab.ui.control.Label
        plot                         matlab.ui.control.Button
        text4                        matlab.ui.control.Label
        AsktosavefiguresCheckBox     matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        working_dir = 'G:\Workspace\Inscopix_Seagate\Projects'; % default dir for loading ROI and GPIO files.
        save_dir = 'G:\Workspace\Inscopix_Seagate\Analysis';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function plot_roi_gpio_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to plot_roi_gpio (see VARARGIN)
%             
%             % Choose default command line output for plot_roi_gpio
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
%             
%             % UIWAIT makes plot_roi_gpio wait for user response (see UIRESUME)
%             % uiwait(app.figure1);
%             
%             % setup default input and output folders
%             folder.csv_file = 'G:\Workspace\Inscopix_Seagate\Projects';
%             setappdata(0, 'folder',folder);
        end

        % Button pushed function: plot
        function plot_Callback(app, event)
            % hObject    handle to plot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            roi_offset_interval = 0.3; % to plot multiple roi traces in a stack, offset interval is used
            
            close all
            
            % get ROI data info
            opts = detectImportOptions(app.edit_roi_readout_file.Value); % creat import options based on file content
            opts.DataLine = 3; % set data line from the third row of csv file. First 2 rows are 'char'
            opts.VariableDescriptionsLine = 2; % set 2nd row as variable description
            ROI_table = readtable(app.edit_roi_readout_file.Value, opts); % import file using modified opts, so data will be number arrays
            [ROI_table, recording_time, ROI_num] = ROI_calc_plot(ROI_table);
            
            ROI_residual = ROI_num-floor(ROI_num/5)*5; % number of ROIs in the last column of plot (5x4 of ROI)
            if ROI_residual ~= 0
                filler_array = zeros(size(ROI_table,1), (5-ROI_residual));
                ROI_table = [ROI_table array2table(filler_array)];
            end
            
            % get GPIO info
            if strcmp(app.edit_gpio_file.Value, "No stimulation applied") || strcmp(app.edit_gpio_file.Value, '')
              gpio_plot = 0; % GPIO info will not be plotted
            else
              gpio_plot = 1; % GPIO info will be plotted
              GPIO_table = readtable(app.edit_gpio_file.Value);
              [ channel, EX_LED_power, GPIO_duration, stimulation ] = GPIO_data_extract(GPIO_table);
            end
            
            % organize data for plot. Each figure has 11x2 subplots. top 5 rows are for ROI traces. last row is for GPIO info
            x_roi = table2array(ROI_table(:, 1));
            roi_group = ceil(ROI_num/5); % number of 'roi_trace x 5' columns
            fig_group = ceil(roi_group/4); % number of figures containing 4 roi_group columns
            
            if app.AsktosavefiguresCheckBox.Value == 1
                save_dir_chosen = uigetdir(app.save_dir, 'Choose a folder to save figures');
            end
            
            roi_group_count = 1; % counting the number of roi_group in the following loop
            for nf = 1 : fig_group % figure num
              ROI_GPIO_handle (nf) = figure(nf);
              set(gcf, 'Units', 'Normalized', 'OuterPosition', [0.05, 0.15, 0.8, 0.85 ]);
              for ng = 1 : 4 % column num
                if (roi_group-(nf-1)*4-ng) >= 0 
                    roi_trace_first = (nf-1)*20+(ng-1)*5+1+1;
                    roi_trace_last = (nf-1)*20+ng*5+1;
    %                 if ROI_num < (nf-1)*20+ng*5
    %                   roi_trace_last = ROI_num+1+(5-ROI_residual); % when last column of roi number is less than 5
    %                   left_over = ROI_num-(ng-1)*5; % number of ROIs in the current column
    %                 else
    %                   roi_trace_last = (nf-1)*20+ng*5+1;
    %                   left_over = 5;
    %                 end
    %                 subplot(6, 4, ng:4:(ng+4*(left_over-1)));
                    subplot(6, 4, ng:4:(ng+4*4));
                    stackedplot(ROI_table(:, [1 roi_trace_first:roi_trace_last]), 'XVariable', 'Time');
                    roi_group_count = roi_group_count+1;
                    if gpio_plot == 1
                      if length(channel)-2 >= 1 % 1st and 2nd channels are SYNC and EX_LED, 3rd is the first stimulation channel
                        subplot(6, 4, 20+ng);
                        for nc = 1 : length(channel)-2
                          gpio_offset = 6;
                          x = channel(nc+2).time_value(:, 1); % time info
                          y{nc} = channel(nc+2).time_value(:, 2)+(length(channel)-2-nc)*gpio_offset;
                          stairs(x, y{nc});
                          hold on
                        end
                        axis([0 recording_time 0 max(y{1})*1.1]);
                        hold off
                        legend(stimulation, 'Interpreter', 'none', 'Location', "SouthOutside");
                        
                      end
                    else
                      % no GPIO info will be plotted
                    end
                end
              end
              [ROIpath, ROIname, ROIext] = fileparts(app.edit_roi_readout_file.Value);
              sgtitle(ROIname(1:(end-17)), 'Interpreter', 'none');
              if app.AsktosavefiguresCheckBox.Value == 1
                  if nf == 1
                      [ROIpath, ROIname, ROIext] = fileparts(app.edit_roi_readout_file.Value);
%                       figure_filename_stem = fullfile(ROIpath, ROIname(1:(end-17))); % get rid of -PP-BP-MC-DFF-ROI from file name.
                  end
                  if save_dir_chosen ~= 0
                      app.save_dir = save_dir_chosen;
                      figure_filename_stem = fullfile(save_dir_chosen, ROIname(1:(end-17))); % get rid of -PP-BP-MC-DFF-ROI from file name.
                      disp(['Saving figure: ', figure_filename_stem,'-',num2str(nf), '.svg'])
                      saveas(ROI_GPIO_handle(nf), [figure_filename_stem,'-',num2str(nf)], 'svg');
                      saveas(ROI_GPIO_handle(nf), [figure_filename_stem,'-',num2str(nf)], 'fig');
                      saveas(ROI_GPIO_handle(nf), [figure_filename_stem,'-',num2str(nf)], 'jpg');
                      if nf == fig_group
                          disp('Completed')
                      end
                  end
              end
            end
        end

        % Button pushed function: pushbutton_browse_gpio_file
        function pushbutton_browse_gpio_file_Callback(app, event)
            % hObject    handle to pushbutton_browse_gpio_file (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [gpio_file_name, csv_dir] = uigetfile([app.edit_roi_readout_file.Value(1:(end-21)),'*.csv'], 'pick a GPIO file'); % input folder
            if isequal(gpio_file_name, 0)
%               disp('User selected Cancel')
            else
              gpio_file = fullfile(csv_dir, gpio_file_name); % file name with full path
              app.edit_gpio_file.Value = gpio_file;
%               disp(['User selected ', gpio_file])
              app.working_dir = csv_dir;
            end
        end

        % Button pushed function: pushbutton_browse_roi_readout_file
        function pushbutton_browse_roi_readout_file_Callback(app, event)
            % hObject    handle to pushbutton_browse_roi_readout_file (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [roi_readout_file_name, csv_dir] = uigetfile(fullfile(app.working_dir,'*.csv; *.svg'), 'Pick a ROI readout file'); % input folder
            if isequal(roi_readout_file_name, 0)
%               disp('User selected Cancel')
            else
              roi_readout_file = fullfile(csv_dir, roi_readout_file_name); % file name with full path
              app.edit_roi_readout_file.Value = roi_readout_file;
              app.edit_gpio_file.Value = "No stimulation applied";
%               disp(['User selected ', roi_readout_file]); % file name with full path
              app.working_dir = csv_dir;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'plot_roi_gpio';
            app.edit_roi_readout_file = uieditfield(app.figure1, 'text');
            app.edit_roi_readout_file.Value = 'G:\Workspace\Inscopix_Seagate\Projects';
            app.edit_gpio_file = uieditfield(app.figure1, 'text');
            app.edit_gpio_file.Value = '"No stimulation applied"';
            app.pushbutton_browse_roi_readout_file = uibutton(app.figure1, 'push');
            app.pushbutton_browse_roi_readout_file.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_browse_roi_readout_file_Callback, true);
            app.pushbutton_browse_roi_readout_file.Text = 'Browse';
            app.pushbutton_browse_gpio_file = uibutton(app.figure1, 'push');
            app.pushbutton_browse_gpio_file.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_browse_gpio_file_Callback, true);
            app.pushbutton_browse_gpio_file.Text = 'Browse';
            app.roi_readout = uilabel(app.figure1);
            app.roi_readout.Text = 'ROI readout file';
            app.gpio_file = uilabel(app.figure1);
            app.gpio_file.Text = 'GPIO file';
            app.plot = uibutton(app.figure1, 'push');
            app.plot.ButtonPushedFcn = createCallbackFcn(app, @plot_Callback, true);
            app.plot.Text = 'Plot ';
            app.text4 = uilabel(app.figure1);
            app.text4.Text = 'Plot ROI and GPIO traces';
            app.AsktosavefiguresCheckBox = uicheckbox(app.figure1);
            app.AsktosavefiguresCheckBox.Text = 'Ask to save figures';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plot_roi_gpio_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @plot_roi_gpio_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end